classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUILDA_GUIsimulator            matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        graph_normalize_Button         matlab.ui.control.StateButton
        Label_25                       matlab.ui.control.Label
        Panel_net_component_label      matlab.ui.control.TextArea
        TabGroup5                      matlab.ui.container.TabGroup
        graph_set_template_Tab         matlab.ui.container.Tab
        Template_choice                matlab.ui.container.ButtonGroup
        componentButton                matlab.ui.control.ToggleButton
        busButton                      matlab.ui.control.ToggleButton
        QButton                        matlab.ui.control.ToggleButton
        IButton                        matlab.ui.control.ToggleButton
        VButton                        matlab.ui.control.ToggleButton
        PButton                        matlab.ui.control.ToggleButton
        graph_set_parameterTab         matlab.ui.container.Tab
        graph_properties_table         matlab.ui.control.Table
        graph_set_controllerTab        matlab.ui.container.Tab
        graph_controller_switch_table  matlab.ui.control.Table
        switch_output_command          matlab.ui.control.StateButton
        Label_22                       matlab.ui.control.Label
        Button_branch_switch           matlab.ui.control.StateButton
        graph_resetSwitch              matlab.ui.control.Switch
        LanguageSwitch                 matlab.ui.control.Switch
        GUILDA                         matlab.ui.control.Image
        Panel_look_modelTree           matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        Tab_info_or_simulation         matlab.ui.container.TabGroup
        networkTab                     matlab.ui.container.Tab
        culc_powerflow_Lamp            matlab.ui.control.Lamp
        Label_23                       matlab.ui.control.Label
        networkLabel                   matlab.ui.control.Label
        Tab_net_info                   matlab.ui.container.TabGroup
        Tab_look_flow                  matlab.ui.container.Tab
        Label_15                       matlab.ui.control.Label
        power_flow_table               matlab.ui.control.Table
        command_flowButton             matlab.ui.control.Button
        Tab_look_equilibrium           matlab.ui.container.Tab
        Label_21                       matlab.ui.control.Label
        equilibrium_table              matlab.ui.control.Table
        command_equilibriumButton      matlab.ui.control.Button
        Tab_parameter                  matlab.ui.container.Tab
        NaNLabel                       matlab.ui.control.Label
        gen_parameter_table            matlab.ui.control.Table
        command_genparaButton          matlab.ui.control.Button
        Tab_branch                     matlab.ui.container.Tab
        NaNLabel_2                     matlab.ui.control.Label
        branch_table                   matlab.ui.control.Table
        command_branchButton           matlab.ui.control.Button
        simulationTab                  matlab.ui.container.Tab
        Label_4                        matlab.ui.control.Label
        TabGroup4                      matlab.ui.container.TabGroup
        OptionSet_Tab                  matlab.ui.container.Tab
        Label_24                       matlab.ui.control.Label
        Button_do_simulation           matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        Button_jump_Outplot_page       matlab.ui.control.Button
        check_out_lamp                 matlab.ui.control.Lamp
        Label_2                        matlab.ui.control.Label
        simulationLabel                matlab.ui.control.Label
        option_table                   matlab.ui.container.TabGroup
        time_set                       matlab.ui.container.Tab
        simulate_time_end              matlab.ui.control.EditField
        sEditField_2Label              matlab.ui.control.Label
        simulate_time_start            matlab.ui.control.EditField
        sEditFieldLabel                matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        defaultLabel                   matlab.ui.control.Label
        simulate_mode_linear           matlab.ui.control.RadioButton
        simulate_mode_nonlinear        matlab.ui.control.RadioButton
        init_set                       matlab.ui.container.Tab
        init_set_table                 matlab.ui.control.Table
        fault_set                      matlab.ui.container.Tab
        fault_set_table                matlab.ui.control.Table
        U_set                          matlab.ui.container.Tab
        Label_16                       matlab.ui.control.Label
        U_set_Table                    matlab.ui.control.Table
        option_reset_Button            matlab.ui.control.Button
        option_memo                    matlab.ui.control.TextArea
        PlotOut_Tab                    matlab.ui.container.Tab
        Panel_animation                matlab.ui.container.Panel
        Tab_select_animation_type      matlab.ui.container.TabGroup
        graphTab                       matlab.ui.container.Tab
        Image_4                        matlab.ui.control.Image
        Label_13                       matlab.ui.control.Label
        graph_animationButton          matlab.ui.control.Button
        polarplotTab                   matlab.ui.container.Tab
        Label_14                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        polar_animationButton          matlab.ui.control.Button
        Image_3                        matlab.ui.control.Image
        Panel_Outplot                  matlab.ui.container.Panel
        Label_5                        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        PlotButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.
    
    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        net_origin
        option_command_text
        power_setable_idx
        
        network_graph_data
        animation_graph_data

        out_changed                 = true;
        net_changed_switch          = false;
        net_changed_color_switch    = false;
    end
    properties (Access = public)
        net
        out
        simulate_time
        option
    end
    
    
    methods (Access = private)
        under_developing_error(app);
        
        %[関数]networkの情報を読み取り「network情報」タブの中を更新
        refresh(app,data);

        %[関数]networkのグラフ構造のプロット及びその設定
        refresh_network_graph(app);
        make_graph_setter(app,data);
        refresh_graph_controller_marker_setter(app);
        refresh_data_table_color(app);
        refresh_graph_when_template_changed(app)
        
        %[関数]simulateのoptionを設定するためのtableを作成
        set_init_option_table(app,data);
        set_fault_option_table(app,option);
        set_U_option_table(app);
        set_simulated_time(app,t);

        %[関数]option設定のtableから変数option及びその設定コマンドの文字列を作成
        set_table2option(app);
        refresh_option_memo(app);
        
        %[関数]networkの平衡点,潮流設定を更新する
        when_power_flow_table_edit(app,event);
        when_gen_parameter_table_edit(app,event);
        when_branch_table_edit(app,enent);

        %[関数]解析結果のプロット関連
        OutPlot_for_app(app);
        Run_animation_graph(app)
        
        %[関数]GUI状の言語を変換
        change_language(app)
        
        %[関数]その他のGUI上の動作を関数化

        function switch_out_lamp_mode(app,onoff)%解析完了ランプ
            if onoff
                app.check_out_lamp.Color = '#00FF00';
            else
                app.check_out_lamp.Color = '#FF0000';
            end
        end

        function switch_OptionReset_Button(app,onoff)%オプションリセットのボタン
            if onoff
                app.option_reset_Button.BackgroundColor = 	'#4DBEEE';
            else
                app.option_reset_Button.BackgroundColor = 	[0.80,0.80,0.80];
            end
        end

        function switch_SimulationRun_Button(app,onoff)%シミュレーション実行ボタン
            if onoff
                app.Button_do_simulation.BackgroundColor = '#4DBEEE';
                app.Button_do_simulation.FontColor = [0,0,0];
                app.Button_do_simulation.Enable = "on";
                switch app.LanguageSwitch.Value
                    case '日本語'
                        app.Button_do_simulation.Text = "simulation開始";
                    case 'English'
                        app.Button_do_simulation.Text = "Run simulation";
                end
            else
                app.Button_do_simulation.BackgroundColor = [0.94,0.94,0.94];
                app.Button_do_simulation.FontColor = [0.80,0.80,0.80];
                app.Button_do_simulation.Enable = "off";
                app.Button_do_simulation.Text = "option未セット";
            end
        end

        function switch_PlotOut_Button(app,onoff)%プロット出力ボタン
            if onoff
                app.Button_jump_Outplot_page.BackgroundColor = 	'#4DBEEE';
            else
                app.Button_jump_Outplot_page.BackgroundColor = 	[0.80,0.80,0.80];
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, net, option, out)
            
            app.net = net;
            app.net_origin = net;
            app.simulate_time(1) = 0;
            app.simulate_time(2) = 50;
            
            if nargin > 2
                app.option = option;
            else
                app.option = struct();
            end
            if nargin > 3
                app.out = out;
            end
            data = net.information('do_report',false);
            
            %simulation option関係
            set_U_option_table(app)
            set_fault_option_table(app)
            set_init_option_table(app,data)
            set_simulated_time(app)
            %set_option_table(app,option)%引数のoption情報をtableに上書き
            refresh_option_memo(app)
            set_table2option(app)

            %network graphの座標軸をoff
            app.Panel_look_modelTree.XAxis.Visible = 'off';
            app.Panel_look_modelTree.YAxis.Visible = 'off';
            title(app.Panel_look_modelTree,'networkの構造図','FontSize',20)
            app.Panel_net_component_label.FontSize = 10;
            make_graph_setter(app,data);
            refresh(app,data)
            app.culc_powerflow_Lamp.Color = [0.8,0.8,0.8];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.GUILDA_GUIsimulator.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {680, 680};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {536, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Image clicked function: GUILDA
        function GUILDAImageClicked(app, event)
            supporters.debugger.count_line_of_directory();
        end

        % Button pushed function: Button_do_simulation
        function Button_do_simulationPushed(app, event)
            app.switch_out_lamp_mode(false);
            app.switch_SimulationRun_Button(false);
            app.switch_PlotOut_Button(false);
            set_table2option(app);
            app.fprintf(app.option_command_text);
            app.out = app.net.simulate(app.simulate_time,app.option);
            pause(10^(-5));
            app.switch_out_lamp_mode(true);
            app.switch_PlotOut_Button(true);
            app.out_changed = true;
        end

        % Button pushed function: Button_jump_Outplot_page
        function Button_jump_Outplot_pageButtonPushed(app, event)
            app.Tab_info_or_simulation.SelectedTab = app.simulationTab;
            app.TabGroup4.SelectedTab = app.PlotOut_Tab;
            app.switch_PlotOut_Button(false);
        end

        % Button pushed function: command_branchButton
        function command_branchButtonPushed(app, event)
            simulation.net_info.disp_command_para(app.net,'branch',app.LanguageSwitch.Value);
        end

        % Button pushed function: command_genparaButton
        function command_genparaButtonPushed(app, event)
            simulation.net_info.disp_command_para(app.net,'gen_para',app.LanguageSwitch.Value);
        end

        % Button pushed function: command_equilibriumButton
        function command_equilibriumButtonPushed(app, event)
            simulation.net_info.disp_command_para(app.net,'equilibrium',app.LanguageSwitch.Value);
        end

        % Button pushed function: command_flowButton
        function command_flowButtonPushed(app, event)
            simulation.net_info.disp_command_para(app.net,'flow',app.LanguageSwitch.Value);
        end

        % Value changed function: Button_branch_switch
        function Button_branch_switchValueChanged(app, event)
            refresh_network_graph(app);
        end

        % Cell edit callback: graph_properties_table
        function graph_properties_tableCellEdit(app, event)
            app.net_changed_color_switch = true; 
            refresh_network_graph(app);
        end

        % Cell edit callback: graph_controller_switch_table
        function graph_controller_switch_tableCellEdit(app, event)
            app.net_changed_color_switch = true;
            refresh_network_graph(app);
        end

        % Cell edit callback: U_set_Table
        function U_set_TableCellEdit(app, event)
            refresh_option_memo(app);
        end

        % Cell edit callback: fault_set_table
        function fault_set_tableCellEdit(app, event)
            refresh_option_memo(app);
        end

        % Cell edit callback: init_set_table
        function init_set_tableCellEdit(app, event)
            refresh_option_memo(app);
        end

        % Button pushed function: option_reset_Button
        function option_reset_ButtonButtonPushed(app, event)
            set_simulated_time(app);
            set_init_option_table(app);
            set_fault_option_table(app);
            set_U_option_table(app);
            refresh_option_memo(app);

            app.switch_OptionReset_Button(false);
        end

        % Value changed function: simulate_time_start
        function simulate_time_startValueChanged(app, event)
            refresh_option_memo(app);
        end

        % Value changed function: simulate_time_end
        function simulate_time_endValueChanged(app, event)
            refresh_option_memo(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            refresh_option_memo(app);
        end

        % Value changed function: graph_resetSwitch
        function graph_resetSwitchValueChanged(app, event)
            app.graph_resetSwitch.Value = '';
            make_graph_setter(app);
            refresh_graph_controller_marker_setter(app);
            app.net_changed_color_switch = true;
            refresh_network_graph(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if isa(app.out,'supporters.for_simulate.sol.DataProcessing')
                app.out.UIplot;
            else
                switch app.LanguageSwitch.Value
                    case '日本語'
                        error('先にシミュレーションを実行してください')
                    case 'English'
                        error('Run the simulation first')
                end
            end
        end

        % Button pushed function: graph_animationButton
        function graph_animationButtonPushed(app, event)
            if isa(app.out,'supporters.for_simulate.sol.DataProcessing')
                app.out.UIanime(app.net)
            else
                switch app.LanguageSwitch.Value
                    case '日本語'
                        error('先にシミュレーションを実行してください')
                    case 'English'
                        error('Run the simulation first')
                end
            end
        end

        % Cell edit callback: gen_parameter_table
        function gen_parameter_tableCellEdit(app, event)
            app.when_gen_parameter_table_edit(event)
        end

        % Cell edit callback: power_flow_table
        function power_flow_tableCellEdit(app, event)
            app.when_power_flow_table_edit(event)            
        end

        % Cell edit callback: branch_table
        function branch_tableCellEdit(app, event)
            app.when_branch_table_edit(event)
        end

        % Value changed function: LanguageSwitch
        function LanguageSwitchValueChanged(app, event)
            app.change_language;
        end

        % Button pushed function: polar_animationButton
        function polar_animationButtonPushed(app, event)
            app.under_developing_error;
        end

        % Selection changed function: Template_choice
        function Template_choiceSelectionChanged(app, event)
            app.refresh_graph_when_template_changed;
        end

        % Value changed function: graph_normalize_Button
        function graph_normalize_ButtonValueChanged(app, event)
            app.net_changed_color_switch=true;
            app.refresh_network_graph;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUILDA_GUIsimulator = uifigure('Visible', 'off');
            app.GUILDA_GUIsimulator.Name = 'GUILDA GUI simulator';
            app.GUILDA_GUIsimulator.Icon = 'GUILDAicon.png';
            app.GUILDA_GUIsimulator.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.GUILDA_GUIsimulator);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel_look_modelTree = uiaxes(app.LeftPanel);
            title(app.Panel_look_modelTree, 'Title')
            xlabel(app.Panel_look_modelTree, 'X')
            ylabel(app.Panel_look_modelTree, 'Y')
            zlabel(app.Panel_look_modelTree, 'Z')
            app.Panel_look_modelTree.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Panel_look_modelTree.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.GUILDA = uiimage(app.LeftPanel);
            app.GUILDA.ImageClickedFcn = createCallbackFcn(app, @GUILDAImageClicked, true);
            app.GUILDA.ImageSource = 'GUILDAicon.png';
            app.LanguageSwitch = uiswitch(app.LeftPanel, 'slider');
            app.LanguageSwitch.Items = {'日本語', 'English'};
            app.LanguageSwitch.ValueChangedFcn = createCallbackFcn(app, @LanguageSwitchValueChanged, true);
            app.LanguageSwitch.Value = '日本語';
            app.graph_resetSwitch = uiswitch(app.LeftPanel, 'slider');
            app.graph_resetSwitch.Items = {'reset', ''};
            app.graph_resetSwitch.ValueChangedFcn = createCallbackFcn(app, @graph_resetSwitchValueChanged, true);
            app.graph_resetSwitch.Value = '';
            app.Button_branch_switch = uibutton(app.LeftPanel, 'state');
            app.Button_branch_switch.ValueChangedFcn = createCallbackFcn(app, @Button_branch_switchValueChanged, true);
            app.Button_branch_switch.Text = 'display branch vlaue';
            app.Label_22 = uilabel(app.LeftPanel);
            app.Label_22.Text = 'グラフ設定';
            app.switch_output_command = uibutton(app.LeftPanel, 'state');
            app.switch_output_command.Text = 'コマンド出力ON';
            app.TabGroup5 = uitabgroup(app.LeftPanel);
            app.graph_set_template_Tab = uitab(app.TabGroup5);
            app.graph_set_template_Tab.Title = 'テンプレート';
            app.Template_choice = uibuttongroup(app.graph_set_template_Tab);
            app.Template_choice.SelectionChangedFcn = createCallbackFcn(app, @Template_choiceSelectionChanged, true);
            app.PButton = uitogglebutton(app.Template_choice);
            app.PButton.Text = '有効電力Pを指標';
            app.VButton = uitogglebutton(app.Template_choice);
            app.VButton.Text = '電圧|V|を指標';
            app.IButton = uitogglebutton(app.Template_choice);
            app.IButton.Text = '電流|I|を指標';
            app.QButton = uitogglebutton(app.Template_choice);
            app.QButton.Text = '無効電力Qを指標';
            app.busButton = uitogglebutton(app.Template_choice);
            app.busButton.Text = '母線の種類で色分け(PV,PQ,slack)';
            app.busButton.Value = true;
            app.componentButton = uitogglebutton(app.Template_choice);
            app.componentButton.Text = '機器の種類で色分け';
            app.graph_set_parameterTab = uitab(app.TabGroup5);
            app.graph_set_parameterTab.Title = '詳細設定';
            app.graph_properties_table = uitable(app.graph_set_parameterTab);
            app.graph_properties_table.ColumnName = {''; 'variable'; 'color'; 'shape'; 'size'; 'prior'};
            app.graph_properties_table.RowName = {};
            app.graph_properties_table.ColumnSortable = [false false false false false true];
            app.graph_properties_table.ColumnEditable = true;
            app.graph_properties_table.CellEditCallback = createCallbackFcn(app, @graph_properties_tableCellEdit, true);
            app.graph_set_controllerTab = uitab(app.TabGroup5);
            app.graph_set_controllerTab.Title = 'controllerマーク';
            app.graph_controller_switch_table = uitable(app.graph_set_controllerTab);
            app.graph_controller_switch_table.ColumnName = {'controller'; '入力bus'; '観測bus'; '色'};
            app.graph_controller_switch_table.RowName = {};
            app.graph_controller_switch_table.ColumnEditable = [false true true true];
            app.graph_controller_switch_table.CellEditCallback = createCallbackFcn(app, @graph_controller_switch_tableCellEdit, true);
            app.Panel_net_component_label = uitextarea(app.LeftPanel);
            app.Panel_net_component_label.Editable = 'off';
            app.Label_25 = uilabel(app.LeftPanel);
            app.Label_25.Text = 'ノードサイズ表';
            app.graph_normalize_Button = uibutton(app.LeftPanel, 'state');
            app.graph_normalize_Button.ValueChangedFcn = createCallbackFcn(app, @graph_normalize_ButtonValueChanged, true);
            app.graph_normalize_Button.Text = 'サイズを正規化';
            app.RightPanel = uipanel(app.GridLayout);
            app.Tab_info_or_simulation = uitabgroup(app.RightPanel);
            app.networkTab = uitab(app.Tab_info_or_simulation);
            app.networkTab.Title = 'network情報';
            app.Tab_net_info = uitabgroup(app.networkTab);
            app.Tab_look_flow = uitab(app.Tab_net_info);
            app.Tab_look_flow.Title = '潮流状態';
            app.command_flowButton = uibutton(app.Tab_look_flow, 'push');
            app.command_flowButton.ButtonPushedFcn = createCallbackFcn(app, @command_flowButtonPushed, true);
            app.command_flowButton.Text = '導出方法';
            app.power_flow_table = uitable(app.Tab_look_flow);
            app.power_flow_table.ColumnName = {'P'; 'Q'; 'Vabs'; 'Varg'};
            app.power_flow_table.RowName = {};
            app.power_flow_table.CellEditCallback = createCallbackFcn(app, @power_flow_tableCellEdit, true);
            app.Label_15 = uilabel(app.Tab_look_flow);
            app.Label_15.Text = '黄枠のみ編集可能(編集方法はコマンドウィンドウに出力)';
            app.Tab_look_equilibrium = uitab(app.Tab_net_info);
            app.Tab_look_equilibrium.Title = '平衡点';
            app.command_equilibriumButton = uibutton(app.Tab_look_equilibrium, 'push');
            app.command_equilibriumButton.ButtonPushedFcn = createCallbackFcn(app, @command_equilibriumButtonPushed, true);
            app.command_equilibriumButton.Text = '導出方法';
            app.equilibrium_table = uitable(app.Tab_look_equilibrium);
            app.equilibrium_table.ColumnName = {'delta'; 'omega'; 'E'; 'Vfd'};
            app.equilibrium_table.RowName = {};
            app.Label_21 = uilabel(app.Tab_look_equilibrium);
            app.Label_21.Text = '平衡点を直接編集することは未だ対応できていません';
            app.Tab_parameter = uitab(app.Tab_net_info);
            app.Tab_parameter.Title = '発電機パラメータ';
            app.command_genparaButton = uibutton(app.Tab_parameter, 'push');
            app.command_genparaButton.ButtonPushedFcn = createCallbackFcn(app, @command_genparaButtonPushed, true);
            app.command_genparaButton.Text = '導出方法';
            app.gen_parameter_table = uitable(app.Tab_parameter);
            app.gen_parameter_table.ColumnName = {'bus_idx'; 'X'; 'Xd'; 'M'};
            app.gen_parameter_table.RowName = {};
            app.gen_parameter_table.CellEditCallback = createCallbackFcn(app, @gen_parameter_tableCellEdit, true);
            app.NaNLabel = uilabel(app.Tab_parameter);
            app.NaNLabel.Text = 'NaNのセル以外は編集可能(編集方法はコマンドウィンドウに出力)';
            app.Tab_branch = uitab(app.Tab_net_info);
            app.Tab_branch.Title = '送電網';
            app.command_branchButton = uibutton(app.Tab_branch, 'push');
            app.command_branchButton.ButtonPushedFcn = createCallbackFcn(app, @command_branchButtonPushed, true);
            app.command_branchButton.Text = '導出方法';
            app.branch_table = uitable(app.Tab_branch);
            app.branch_table.ColumnName = {'bus'; 'bus_to'; 'x_real'; 'ximag'};
            app.branch_table.RowName = {};
            app.branch_table.CellEditCallback = createCallbackFcn(app, @branch_tableCellEdit, true);
            app.NaNLabel_2 = uilabel(app.Tab_branch);
            app.NaNLabel_2.Text = 'NaNのセル以外は編集可能(編集方法はコマンドウィンドウに出力)';
            app.networkLabel = uilabel(app.networkTab);
            app.networkLabel.Text = ' network情報一覧';
            app.Label_23 = uilabel(app.networkTab);
            app.Label_23.Text = '潮流計算中...';
            app.culc_powerflow_Lamp = uilamp(app.networkTab);
            app.simulationTab = uitab(app.Tab_info_or_simulation);
            app.simulationTab.Title = 'simulation';
            app.TabGroup4 = uitabgroup(app.simulationTab);
            app.OptionSet_Tab = uitab(app.TabGroup4);
            app.OptionSet_Tab.Title = '条件設定';
            app.option_memo = uitextarea(app.OptionSet_Tab);
            app.option_memo.Editable = 'off';
            app.option_reset_Button = uibutton(app.OptionSet_Tab, 'push');
            app.option_reset_Button.ButtonPushedFcn = createCallbackFcn(app, @option_reset_ButtonButtonPushed, true);
            app.option_reset_Button.Text = 'optionリセット';
            app.option_table = uitabgroup(app.OptionSet_Tab);
            app.time_set = uitab(app.option_table);
            app.time_set.Title = '時間';
            app.ButtonGroup = uibuttongroup(app.time_set);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.simulate_mode_nonlinear = uiradiobutton(app.ButtonGroup);
            app.simulate_mode_nonlinear.Text = '非線形モデルでシミュレーション';
            app.simulate_mode_nonlinear.Value = true;
            app.simulate_mode_linear = uiradiobutton(app.ButtonGroup);
            app.simulate_mode_linear.Text = '近似線形化モデルでシミュレーション';
            app.defaultLabel = uilabel(app.ButtonGroup);
            app.defaultLabel.Text = '(default)';
            app.sEditFieldLabel = uilabel(app.time_set);
            app.sEditFieldLabel.Text = '開始時刻(s)：';
            app.simulate_time_start = uieditfield(app.time_set, 'text');
            app.simulate_time_start.ValueChangedFcn = createCallbackFcn(app, @simulate_time_startValueChanged, true);
            app.sEditField_2Label = uilabel(app.time_set);
            app.sEditField_2Label.Text = '〜　終了時刻(s)：';
            app.simulate_time_end = uieditfield(app.time_set, 'text');
            app.simulate_time_end.ValueChangedFcn = createCallbackFcn(app, @simulate_time_endValueChanged, true);
            app.init_set = uitab(app.option_table);
            app.init_set.Title = '初期値';
            app.init_set_table = uitable(app.init_set);
            app.init_set_table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.init_set_table.RowName = {};
            app.init_set_table.CellEditCallback = createCallbackFcn(app, @init_set_tableCellEdit, true);
            app.fault_set = uitab(app.option_table);
            app.fault_set.Title = '地絡';
            app.fault_set_table = uitable(app.fault_set);
            app.fault_set_table.ColumnName = {'地絡開始時刻'; '地絡終了時刻'; '発生母線'};
            app.fault_set_table.RowName = {};
            app.fault_set_table.ColumnSortable = [true true false];
            app.fault_set_table.ColumnEditable = true;
            app.fault_set_table.CellEditCallback = createCallbackFcn(app, @fault_set_tableCellEdit, true);
            app.U_set = uitab(app.option_table);
            app.U_set.Title = '入力';
            app.U_set_Table = uitable(app.U_set);
            app.U_set_Table.ColumnName = {'u'; 'u_idx'};
            app.U_set_Table.RowName = {};
            app.U_set_Table.ColumnEditable = true;
            app.U_set_Table.CellEditCallback = createCallbackFcn(app, @U_set_TableCellEdit, true);
            app.Label_16 = uilabel(app.U_set);
            app.Label_16.Text = '未対応';
            app.simulationLabel = uilabel(app.OptionSet_Tab);
            app.simulationLabel.Text = 'simulation 条件一覧';
            app.Panel = uipanel(app.OptionSet_Tab);
            app.Panel.Title = '解析結果出力 ';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '解析済 ';
            app.check_out_lamp = uilamp(app.Panel);
            app.Button_jump_Outplot_page = uibutton(app.Panel, 'push');
            app.Button_jump_Outplot_page.ButtonPushedFcn = createCallbackFcn(app, @Button_jump_Outplot_pageButtonPushed, true);
            app.Button_jump_Outplot_page.Text = 'illustration';
            app.Button_do_simulation = uibutton(app.OptionSet_Tab, 'push');
            app.Button_do_simulation.ButtonPushedFcn = createCallbackFcn(app, @Button_do_simulationPushed, true);
            app.Button_do_simulation.Text = 'simulation開始';
            app.Label_24 = uilabel(app.OptionSet_Tab);
            app.Label_24.Text = '⬇︎';
            app.PlotOut_Tab = uitab(app.TabGroup4);
            app.PlotOut_Tab.Title = '解析結果';
            app.Panel_Outplot = uipanel(app.PlotOut_Tab);
            app.Panel_Outplot.Title = '応答プロット';
            app.PlotButton = uibutton(app.Panel_Outplot, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.Image = uiimage(app.Panel_Outplot);
            app.Image.ImageSource = 'sample_plot.png';
            app.Label_5 = uilabel(app.Panel_Outplot);
            app.Label_5.Text = 'イメージ';
            app.Panel_animation = uipanel(app.PlotOut_Tab);
            app.Panel_animation.Title = '応答アニメーション';
            app.Tab_select_animation_type = uitabgroup(app.Panel_animation);
            app.graphTab = uitab(app.Tab_select_animation_type);
            app.graphTab.Title = 'graph';
            app.graph_animationButton = uibutton(app.graphTab, 'push');
            app.graph_animationButton.ButtonPushedFcn = createCallbackFcn(app, @graph_animationButtonPushed, true);
            app.graph_animationButton.Text = 'animation';
            app.Label_13 = uilabel(app.graphTab);
            app.Label_13.Text = 'イメージ';
            app.Image_4 = uiimage(app.graphTab);
            app.Image_4.Enable = 'off';
            app.Image_4.ImageSource = 'sample_graphplot.png';
            app.polarplotTab = uitab(app.Tab_select_animation_type);
            app.polarplotTab.Title = 'polar plot';
            app.Image_3 = uiimage(app.polarplotTab);
            app.polar_animationButton = uibutton(app.polarplotTab, 'push');
            app.polar_animationButton.ButtonPushedFcn = createCallbackFcn(app, @polar_animationButtonPushed, true);
            app.polar_animationButton.Text = 'animation';
            app.Label_8 = uilabel(app.polarplotTab);
            app.Label_8.Text = '各発電機の回転子偏角を極座標上にプロットします。';
            app.Label_9 = uilabel(app.polarplotTab);
            app.Label_9.Text = 'システムの応答が安定である場合は、';
            app.Label_10 = uilabel(app.polarplotTab);
            app.Label_10.Text = '全ての発電機が同期していいることが分かると思います。';
            app.Label_11 = uilabel(app.polarplotTab);
            app.Label_11.Text = '※このプロットのコマンドは出力されません';
            app.Label_14 = uilabel(app.polarplotTab);
            app.Label_14.Text = 'イメージ';
            app.Label_4 = uilabel(app.simulationTab);
            app.Label_4.Text = ' 数値解析ページ';
            % Show the figure after all components are created
            app.GUILDA_GUIsimulator.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUILDA_GUIsimulator)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUILDA_GUIsimulator)
        end
    end
end