classdef LiveSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Image                          matlab.ui.control.Image
        Tab_SImulationSetting          matlab.ui.container.TabGroup
        Tab_component                  matlab.ui.container.Tab
        SelectComponentListBox         matlab.ui.control.ListBox
        SelectComponentLabel           matlab.ui.control.Label
        Panel_component                matlab.ui.container.Panel
        u_equilibriumLabel             matlab.ui.control.Label
        parameterLabel                 matlab.ui.control.Label
        Tab_comp_ust                   matlab.ui.control.Table
        Tab_comp_parameter             matlab.ui.control.Table
        Lamp_comp_parallel             matlab.ui.control.Lamp
        Switch_comp_parallel           matlab.ui.control.ToggleSwitch
        parallelSwitchLabel            matlab.ui.control.Label
        Tab_controller                 matlab.ui.container.Tab
        SelectControllerLabel          matlab.ui.control.Label
        SelectControllerListBox        matlab.ui.control.ListBox
        Panel_controller               matlab.ui.container.Panel
        GraphmarkingButtonGroup        matlab.ui.container.ButtonGroup
        index_inputButton              matlab.ui.control.ToggleButton
        index_observeButton            matlab.ui.control.ToggleButton
        parameterLabel_2               matlab.ui.control.Label
        Tab_con_parameter              matlab.ui.control.Table
        Lamp_con_parallel              matlab.ui.control.Lamp
        Switch_con_parallel            matlab.ui.control.ToggleSwitch
        parallelSwitch_2Label          matlab.ui.control.Label
        Tab_groundfault                matlab.ui.container.Tab
        TextBox_fault_busidx           matlab.ui.control.EditField
        BusindexwheregroundfaultoccursEditFieldLabel  matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        TextBox_fault_time             matlab.ui.control.EditField
        LengthoftimeofoccurrenceLabel  matlab.ui.control.Label
        Button_fault                   matlab.ui.control.Button
        Axes_graph                     matlab.ui.control.UIAxes
        CenterPanel                    matlab.ui.container.Panel
        RightPanel                     matlab.ui.container.Panel
        para_window                    matlab.ui.container.ButtonGroup
        para                           matlab.ui.container.CheckBoxTree
        powerflow                      matlab.ui.container.TreeNode
        V                              matlab.ui.container.TreeNode
        Vabs                           matlab.ui.container.TreeNode
        Vangle                         matlab.ui.container.TreeNode
        Vreal                          matlab.ui.container.TreeNode
        Vimag                          matlab.ui.container.TreeNode
        I                              matlab.ui.container.TreeNode
        Iabs                           matlab.ui.container.TreeNode
        Iangle                         matlab.ui.container.TreeNode
        Ireal                          matlab.ui.container.TreeNode
        Iimag                          matlab.ui.container.TreeNode
        power                          matlab.ui.container.TreeNode
        P                              matlab.ui.container.TreeNode
        Q                              matlab.ui.container.TreeNode
        S                              matlab.ui.container.TreeNode
        Factor                         matlab.ui.container.TreeNode
        X                              matlab.ui.container.TreeNode
        bus_window                     matlab.ui.container.ButtonGroup
        specify                        matlab.ui.control.EditField
        specify_tf                     matlab.ui.control.CheckBox
        bus_idx                        matlab.ui.container.CheckBoxTree
        all_bus                        matlab.ui.container.TreeNode
        bus_type                       matlab.ui.container.TreeNode
        bus_PV                         matlab.ui.container.TreeNode
        bus_PQ                         matlab.ui.container.TreeNode
        bus_slack                      matlab.ui.container.TreeNode
        all_component                  matlab.ui.container.TreeNode
        ResponseLabel                  matlab.ui.control.Label
        Axes_response                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties(Access = private)
        net % power_network class
        data 
        plot_component
        plot_state

        setting_component
        setting_controller

        simulator
        Events_simulation = false;

        GraphData
    end

    methods
        refresh_response_setting(app)

        function [value,isterminal,direction] = myEventsFcn(app,~,~)
            value = app.Events_simulation;
            isterminal = 1;
            direction  = 0;
            if value
                app.Events_simulation = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, net, varargin)
            app.net = net;
            net_data = net.information('do_report',false);            

            % Create checkbox for each component
            clist_all = net_data.component{:,'class'};
            clist = unique(clist_all);
            for i = 1:numel(clist)
                app.plot_component{i} = uitreenode(app.all_component);
                app.plot_component{i}.Text = clist(i);
                app.plot_component{i}.Tag  = clist(i);
            end
            app.SelectComponentListBox.Items  = (1:numel(clist_all))'+"."+clist_all;
            app.SelectComponentListBox.ItemsData = tools.cellfun(@(b) b.component, net.a_bus);

            con_data = [net_data.controller_local;net_data.controller_global];
            if ~isempty(con_data)
                clist_all = con_data{:,'class'};
                app.SelectControllerListBox.Items = (1:numel(clist_all))'+"."+clist_all;
                app.SelectControllerListBox.ItemsData = [net.a_controller_local;net.a_controller_global];
            end

            % Create checkbox for each state of system
            uni_state = net_data.x_equilibrium.component.Properties.VariableNames;
            for  i = 1:numel(uni_state)
                app.plot_state{i} = uitreenode(app.X);
                app.plot_state{i}.Text = uni_state{i};
                app.plot_state{i}.Tag  = uni_state{i};
            end

            app.data = net_data;

            % app.SelectControllerListBoxValueChanged([])
            app.simulator =  supporters.for_simulate.solver(net,[0,inf],'do_report',false,varargin{:});
            app.simulator.additional_odeopt = {'Events',@(t,y)app.myEventsFcn(t,y)};
            %app.simulator.run;

            app.GraphData = supporters.for_graph.map_forUI(net,app.Axes_graph);

        end

        % Value changed function: specify
        function specifyValueChanged(app, event)
            value = app.specify.Value;
            if numel(str2num(value))==0
                app.specify.FontColor = [1.00,0.00,0.00];
                app.specify.Value = 'e.g. [1,2,3] or 1:3';
            else
                app.specify.FontColor = [0.15,0.15,0.15];
            end
            app.refresh_response_setting
        end

        % Callback function: bus_idx
        function bus_idxCheckedNodesChanged(app, event)
            app.specify_tf.Value = false;
            app.specify.BackgroundColor = [0.65,0.65,0.65];
            app.refresh_response_setting
        end

        % Value changed function: specify_tf
        function specify_tfValueChanged(app, event)
            app.bus_idx.CheckedNodes = [];
            if app.specify_tf.Value
                app.specify.BackgroundColor = [0.73,0.91,0.98];
            else
                app.specify.BackgroundColor = [0.65,0.65,0.65];
            end
            app.refresh_response_setting
        end

        % Value changed function: SelectComponentListBox
        function SelectComponentListBoxValueChanged(app, event)
            c = app.SelectComponentListBox.Value;
            app.Panel_component.Title = c.Tag;
            
            app.Tab_comp_parameter.RowName    = c.parameter.Properties.VariableNames;
            app.Tab_comp_parameter.ColumnName = {'Name','Value'};
            app.Tab_comp_parameter.Data       = c.parameter{1,:}.';
            app.Tab_comp_parameter.ColumnEditable = [false,true];

            app.Tab_comp_ust.RowName    = c.get_port_name;
            app.Tab_comp_ust.ColumnName = {'Value'}; 
            app.Tab_comp_ust.Data       = c.u_equilibrium(:);
            app.Tab_comp_ust.ColumnEditable = [false,true];

            app.Switch_comp_parallel.Value = c.parallel;

        end

        % Value changed function: SelectControllerListBox
        function SelectControllerListBoxValueChanged(app, event)
            c = app.SelectControllerListBox.Value;
            app.Panel_controller.Title = c.Tag;
            
            app.Tab_con_parameter.RowName    = c.parameter.Properties.VariableNames;
            app.Tab_con_parameter.ColumnName = {'Value'};
            app.Tab_con_parameter.Data       = c.parameter{1,:}.';
            app.Tab_con_parameter.ColumnEditable = [false,true];

            app.Switch_comp_parallel.Value = c.parallel;
        end

        % Cell edit callback: Tab_comp_parameter
        function Tab_comp_parameterCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newData = str2double(newData);
            if isnan(newData)
                app.Tab_comp_parameter.Data(indices(1),indices(2)) = event.PreviousData;
            else
                c = app.SelectComponentListBox.Value;
                c.parameter{:,indices(1)} = newData;
            end
        end

        % Cell edit callback: Tab_con_parameter
        function Tab_con_parameterCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newData = str2double(newData);
            if isnan(newData)
                app.Tab_con_parameter.Data(indices(1),indices(2)) = event.PreviousData;
            else
                c = app.SelectControllerListBox.Value;
                c.parameter{:,indices(1)} = newData;
            end
        end

        % Cell edit callback: Tab_comp_ust
        function Tab_comp_ustCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newData = str2double(newData);
            if isnan(newData)
                app.Tab_comp_ust.Data(indices(1),indices(2)) = event.PreviousData;
            else
                c = app.SelectComponentListBox.Value;
                c.u_equilibrium(indices(1)) = newData;
            end
        end

        % Value changed function: Switch_comp_parallel
        function Switch_comp_parallelValueChanged(app, event)
            value = app.Switch_comp_parallel.Value;
            
            name = app.Panel_component.Title;
            idx  = num2double(name(1:find(name=='.',1,"first")-1));
            
            switch value
                case 'on'
                    app.Lamp_comp_parallel.Color = [0.00,1.00,0.00];
                    app.SelectComponentListBox.Value.connect;
                    app.simulator.additional_I0bus = setdiff(app.simulator.additional_V0const, idx);
                case 'off'
                    app.Lamp_comp_parallel.Color = [1.00,0.00,0.00];
                    app.SelectComponentListBox.Value.disconnect
                    app.simulator.additional_I0bus = union(app.simulator.additional_V0const, idx);
            end
            app.Events_simulation = true;

        end

        % Value changed function: Switch_con_parallel
        function Switch_con_parallelValueChanged(app, event)
            value = app.Switch_con_parallel.Value;
            switch value
                case 'on'
                    app.Lamp_con_parallel.Color = [0.00,1.00,0.00];
                    app.SelectControllerListBox.Value.connect
                case 'off'
                    app.Lamp_con_parallel.Color = [1.00,0.00,0.00];
                    app.SelectControllerListBox.Value.disconnect
            end
            warning('controllerのon/off切り替えは実装中です...')
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            supporters.debugger.count_line_of_directory
        end

        % Value changed function: TextBox_fault_busidx
        function TextBox_fault_busidxValueChanged(app, event)
            value = app.TextBox_fault_busidx.Value;
            if isempty(str2num(value))%#ok
                app.TextBox_fault_busidx.Value = event.PreviousData;
            end
        end

        % Value changed function: TextBox_fault_time
        function TextBox_fault_timeValueChanged(app, event)
            value = app.TextBox_fault_time.Value;
            if isempty(str2num(value))%#ok
                app.TextBox_fault_time.Value = event.PreviousData;
            end
        end

        % Button pushed function: Button_fault
        function Button_faultPushed(app, event)
            idx = str2num( app.TextBox_fault_busidx.Value );%#ok
            time    = str2num( app.TextBox_fault_time.Value   );%#ok

            app.simulator.additional_V0bus = union(app.simulator.additional_V0bus, idx);
            app.Events_simulation = true;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {654, 654, 654};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {654, 654};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {475, '1x', 715};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Axes_graph = uiaxes(app.LeftPanel);
            title(app.Axes_graph, 'Title')
            xlabel(app.Axes_graph, 'X')
            ylabel(app.Axes_graph, 'Y')
            zlabel(app.Axes_graph, 'Z')
            app.Tab_SImulationSetting = uitabgroup(app.LeftPanel);
            app.Tab_component = uitab(app.Tab_SImulationSetting);
            app.Tab_component.Title = 'component';
            app.Panel_component = uipanel(app.Tab_component);
            app.Panel_component.Title = 'choose component';
            app.parallelSwitchLabel = uilabel(app.Panel_component);
            app.parallelSwitchLabel.Text = 'parallel';
            app.Switch_comp_parallel = uiswitch(app.Panel_component, 'toggle');
            app.Switch_comp_parallel.Items = {'off', 'on'};
            app.Switch_comp_parallel.ValueChangedFcn = createCallbackFcn(app, @Switch_comp_parallelValueChanged, true);
            app.Switch_comp_parallel.Value = 'on';
            app.Lamp_comp_parallel = uilamp(app.Panel_component);
            app.Tab_comp_parameter = uitable(app.Panel_component);
            app.Tab_comp_parameter.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tab_comp_parameter.RowName = {};
            app.Tab_comp_parameter.CellEditCallback = createCallbackFcn(app, @Tab_comp_parameterCellEdit, true);
            app.Tab_comp_ust = uitable(app.Panel_component);
            app.Tab_comp_ust.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tab_comp_ust.RowName = {};
            app.Tab_comp_ust.CellEditCallback = createCallbackFcn(app, @Tab_comp_ustCellEdit, true);
            app.parameterLabel = uilabel(app.Panel_component);
            app.parameterLabel.Text = 'parameter';
            app.u_equilibriumLabel = uilabel(app.Panel_component);
            app.u_equilibriumLabel.Text = 'u_equilibrium';
            app.SelectComponentLabel = uilabel(app.Tab_component);
            app.SelectComponentLabel.Text = 'Select Component : ';
            app.SelectComponentListBox = uilistbox(app.Tab_component);
            app.SelectComponentListBox.ValueChangedFcn = createCallbackFcn(app, @SelectComponentListBoxValueChanged, true);
            app.Tab_controller = uitab(app.Tab_SImulationSetting);
            app.Tab_controller.Title = 'controller';
            app.Panel_controller = uipanel(app.Tab_controller);
            app.Panel_controller.Title = 'choose controller';
            app.parallelSwitch_2Label = uilabel(app.Panel_controller);
            app.parallelSwitch_2Label.Text = 'parallel';
            app.Switch_con_parallel = uiswitch(app.Panel_controller, 'toggle');
            app.Switch_con_parallel.ValueChangedFcn = createCallbackFcn(app, @Switch_con_parallelValueChanged, true);
            app.Lamp_con_parallel = uilamp(app.Panel_controller);
            app.Tab_con_parameter = uitable(app.Panel_controller);
            app.Tab_con_parameter.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tab_con_parameter.RowName = {};
            app.Tab_con_parameter.CellEditCallback = createCallbackFcn(app, @Tab_con_parameterCellEdit, true);
            app.parameterLabel_2 = uilabel(app.Panel_controller);
            app.parameterLabel_2.Text = 'parameter';
            app.GraphmarkingButtonGroup = uibuttongroup(app.Panel_controller);
            app.GraphmarkingButtonGroup.Title = 'Graph marking';
            app.index_observeButton = uitogglebutton(app.GraphmarkingButtonGroup);
            app.index_observeButton.Text = 'index_observe';
            app.index_observeButton.Value = true;
            app.index_inputButton = uitogglebutton(app.GraphmarkingButtonGroup);
            app.index_inputButton.Text = 'index_input';
            app.SelectControllerListBox = uilistbox(app.Tab_controller);
            app.SelectControllerListBox.ValueChangedFcn = createCallbackFcn(app, @SelectControllerListBoxValueChanged, true);
            app.SelectControllerLabel = uilabel(app.Tab_controller);
            app.SelectControllerLabel.Text = 'Select Controller : ';
            app.Tab_groundfault = uitab(app.Tab_SImulationSetting);
            app.Tab_groundfault.Title = 'ground fault';
            app.Button_fault = uibutton(app.Tab_groundfault, 'push');
            app.Button_fault.ButtonPushedFcn = createCallbackFcn(app, @Button_faultPushed, true);
            app.Button_fault.Text = 'fault';
            app.LengthoftimeofoccurrenceLabel = uilabel(app.Tab_groundfault);
            app.LengthoftimeofoccurrenceLabel.Text = 'Length of time of occurrence';
            app.TextBox_fault_time = uieditfield(app.Tab_groundfault, 'text');
            app.TextBox_fault_time.ValueChangedFcn = createCallbackFcn(app, @TextBox_fault_timeValueChanged, true);
            app.sLabel = uilabel(app.Tab_groundfault);
            app.sLabel.Text = '(s)';
            app.BusindexwheregroundfaultoccursEditFieldLabel = uilabel(app.Tab_groundfault);
            app.BusindexwheregroundfaultoccursEditFieldLabel.Text = 'Bus index where ground fault occurs';
            app.TextBox_fault_busidx = uieditfield(app.Tab_groundfault, 'text');
            app.TextBox_fault_busidx.ValueChangedFcn = createCallbackFcn(app, @TextBox_fault_busidxValueChanged, true);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'GUILDAicon.png';
            app.CenterPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.Axes_response = uiaxes(app.RightPanel);
            title(app.Axes_response, 'Title')
            xlabel(app.Axes_response, 'X')
            ylabel(app.Axes_response, 'Y')
            zlabel(app.Axes_response, 'Z')
            app.ResponseLabel = uilabel(app.RightPanel);
            app.ResponseLabel.Text = 'Response';
            app.bus_window = uibuttongroup(app.RightPanel);
            app.bus_window.Title = 'プロット対象母線';
            app.bus_idx = uitree(app.bus_window, 'checkbox');
            app.all_bus = uitreenode(app.bus_idx);
            app.all_bus.Tag = 'all_bus';
            app.all_bus.Text = '全ての母線';
            app.bus_type = uitreenode(app.all_bus);
            app.bus_type.Tag = 'all_bus';
            app.bus_type.Text = '母線の種類で選択';
            app.bus_PV = uitreenode(app.bus_type);
            app.bus_PV.Tag = 'bus_PV';
            app.bus_PV.Text = 'PV母線';
            app.bus_PQ = uitreenode(app.bus_type);
            app.bus_PQ.Tag = 'bus_PQ';
            app.bus_PQ.Text = 'PQ母線';
            app.bus_slack = uitreenode(app.bus_type);
            app.bus_slack.Tag = 'bus_slack';
            app.bus_slack.Text = 'slack母線';
            app.all_component = uitreenode(app.all_bus);
            app.all_component.Tag = 'all_bus';
            app.all_component.Text = '機器の種類で選択';
            % Assign Checked Nodes
            app.bus_idx.CheckedNodesChangedFcn = createCallbackFcn(app, @bus_idxCheckedNodesChanged, true);
            app.specify_tf = uicheckbox(app.bus_window);
            app.specify_tf.ValueChangedFcn = createCallbackFcn(app, @specify_tfValueChanged, true);
            app.specify_tf.Text = '特定の母線のみ';
            app.specify = uieditfield(app.bus_window, 'text');
            app.specify.ValueChangedFcn = createCallbackFcn(app, @specifyValueChanged, true);
            app.specify.Value = 'e.g. [1,2,3] or 1:3';
            app.para_window = uibuttongroup(app.RightPanel);
            app.para_window.Title = 'プロット対象パラメータ';
            app.para = uitree(app.para_window, 'checkbox');
            app.powerflow = uitreenode(app.para);
            app.powerflow.Tag = 'powerflow';
            app.powerflow.Text = '潮流値';
            app.V = uitreenode(app.powerflow);
            app.V.Tag = 'V';
            app.V.Text = '母線電圧';
            app.Vabs = uitreenode(app.V);
            app.Vabs.Tag = 'Vabs';
            app.Vabs.Text = '絶対値';
            app.Vangle = uitreenode(app.V);
            app.Vangle.Tag = 'Vangle';
            app.Vangle.Text = '偏角';
            app.Vreal = uitreenode(app.V);
            app.Vreal.Tag = 'Vreal';
            app.Vreal.Text = '実部';
            app.Vimag = uitreenode(app.V);
            app.Vimag.Tag = 'Vimag';
            app.Vimag.Text = '虚部';
            app.I = uitreenode(app.powerflow);
            app.I.Tag = 'I';
            app.I.Text = '母線電流';
            app.Iabs = uitreenode(app.I);
            app.Iabs.Tag = 'Iabs';
            app.Iabs.Text = '絶対値';
            app.Iangle = uitreenode(app.I);
            app.Iangle.Tag = 'Iangle';
            app.Iangle.Text = '偏角';
            app.Ireal = uitreenode(app.I);
            app.Ireal.Tag = 'Ireal';
            app.Ireal.Text = '実部';
            app.Iimag = uitreenode(app.I);
            app.Iimag.Tag = 'Iimag';
            app.Iimag.Text = '虚部';
            app.power = uitreenode(app.powerflow);
            app.power.Tag = 'power';
            app.power.Text = '電力';
            app.P = uitreenode(app.power);
            app.P.Tag = 'P';
            app.P.Text = '有効電力';
            app.Q = uitreenode(app.power);
            app.Q.Tag = 'Q';
            app.Q.Text = '無効電力';
            app.S = uitreenode(app.power);
            app.S.Tag = 'S';
            app.S.Text = '皮相電力';
            app.Factor = uitreenode(app.power);
            app.Factor.Tag = 'Factor';
            app.Factor.Text = '力率';
            app.X = uitreenode(app.para);
            app.X.Tag = 'X';
            app.X.Text = '機器の状態';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveSimulation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end