classdef UIanime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab_Size            matlab.ui.container.Tab
        EditField_Size      matlab.ui.control.NumericEditField
        ValueLabel          matlab.ui.control.Label
        CheckBox_Size       matlab.ui.control.CheckBox
        ParaWIndow_Size     matlab.ui.container.ButtonGroup
        para_Size           matlab.ui.container.CheckBoxTree
        spowerflow          matlab.ui.container.TreeNode
        sV                  matlab.ui.container.TreeNode
        sVabs               matlab.ui.container.TreeNode
        sVangle             matlab.ui.container.TreeNode
        sVreal              matlab.ui.container.TreeNode
        sVimag              matlab.ui.container.TreeNode
        sI                  matlab.ui.container.TreeNode
        sIabs               matlab.ui.container.TreeNode
        sIangle             matlab.ui.container.TreeNode
        sIreal              matlab.ui.container.TreeNode
        sIimag              matlab.ui.container.TreeNode
        spower              matlab.ui.container.TreeNode
        sP                  matlab.ui.container.TreeNode
        sQ                  matlab.ui.container.TreeNode
        sS                  matlab.ui.container.TreeNode
        sFactor             matlab.ui.container.TreeNode
        sX                  matlab.ui.container.TreeNode
        sPara               matlab.ui.container.TreeNode
        Tab_color           matlab.ui.container.Tab
        EditField_color     matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        CheckBox_color      matlab.ui.control.CheckBox
        ParaWindow_color    matlab.ui.container.ButtonGroup
        para_color          matlab.ui.container.CheckBoxTree
        cpowerflow          matlab.ui.container.TreeNode
        cV                  matlab.ui.container.TreeNode
        cVabs               matlab.ui.container.TreeNode
        cVangle             matlab.ui.container.TreeNode
        cVreal              matlab.ui.container.TreeNode
        cVimag              matlab.ui.container.TreeNode
        cI                  matlab.ui.container.TreeNode
        cIabs               matlab.ui.container.TreeNode
        cIangle             matlab.ui.container.TreeNode
        cIreal              matlab.ui.container.TreeNode
        cIimag              matlab.ui.container.TreeNode
        cpower              matlab.ui.container.TreeNode
        cP                  matlab.ui.container.TreeNode
        cQ                  matlab.ui.container.TreeNode
        cS                  matlab.ui.container.TreeNode
        cFactor             matlab.ui.container.TreeNode
        cX                  matlab.ui.container.TreeNode
        cPara               matlab.ui.container.TreeNode
        Tab_Height          matlab.ui.container.Tab
        EditField_Height    matlab.ui.control.NumericEditField
        EditField_3Label    matlab.ui.control.Label
        CheckBox_Height     matlab.ui.control.CheckBox
        ParaWindow_Height   matlab.ui.container.ButtonGroup
        para_Height         matlab.ui.container.CheckBoxTree
        hpowerflow          matlab.ui.container.TreeNode
        hV                  matlab.ui.container.TreeNode
        hVabs               matlab.ui.container.TreeNode
        hVangle             matlab.ui.container.TreeNode
        hVreal              matlab.ui.container.TreeNode
        hVimag              matlab.ui.container.TreeNode
        hI                  matlab.ui.container.TreeNode
        hIabs               matlab.ui.container.TreeNode
        hIangle             matlab.ui.container.TreeNode
        hIreal              matlab.ui.container.TreeNode
        hIimag              matlab.ui.container.TreeNode
        hpower              matlab.ui.container.TreeNode
        hP                  matlab.ui.container.TreeNode
        hQ                  matlab.ui.container.TreeNode
        hS                  matlab.ui.container.TreeNode
        hFactor             matlab.ui.container.TreeNode
        hX                  matlab.ui.container.TreeNode
        hPara               matlab.ui.container.TreeNode
        ButtonGroup_Gstyle  matlab.ui.container.ButtonGroup
        GStyle1Button       matlab.ui.control.ToggleButton
        GStyle2Button       matlab.ui.control.ToggleButton
        GStyle3Button       matlab.ui.control.ToggleButton
        Image_Gstyle        matlab.ui.control.Image
        Language            matlab.ui.control.Switch
        title               matlab.ui.control.Label
        GenerateButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        net
        out
        state
        parameter

        textBackColor = {[0.30,0.30,0.30],[1,1,1]};

        check_box_switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, out, net)
            app.out = out;
            app.net = net;
            
            app.Language.Value = '日本語';

            net_data = app.out.net_data;

            % Create checkbox for each state of system
            uni_state = net_data.x_equilibrium.component.Properties.VariableNames;
            for  i = 1:numel(uni_state)
                app.state{i}.size = uitreenode(app.sX);
                app.state{i}.size.Text = uni_state{i};
                app.state{i}.size.Tag  = uni_state{i};
                app.state{i}.color = uitreenode(app.cX);
                app.state{i}.color.Text = uni_state{i};
                app.state{i}.color.Tag  = uni_state{i};
                app.state{i}.Height = uitreenode(app.hX);
                app.state{i}.Height.Text = uni_state{i};
                app.state{i}.Height.Tag  = uni_state{i};
            end 
            uni_para = net_data.parameter.component.Properties.VariableNames;
            for  i = 1:numel(uni_para)
                app.parameter{i}.size = uitreenode(app.sPara);
                app.parameter{i}.size.Text = uni_para{i};
                app.parameter{i}.size.Tag  = uni_para{i};
                app.parameter{i}.color = uitreenode(app.cPara);
                app.parameter{i}.color.Text = uni_para{i};
                app.parameter{i}.color.Tag = uni_para{i};
                app.parameter{i}.Height = uitreenode(app.hPara);
                app.parameter{i}.Height.Text = uni_para{i};
                app.parameter{i}.Height.Tag = uni_para{i};
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            style = app.ButtonGroup_Gstyle.SelectedObject.Text;
            
            %size
            if app.CheckBox_Size.Value
                s = app.EditField_Size.Value;
            else
                s = app.para_Size.CheckedNodes(1).Tag;
            end
            
            %color
            if app.CheckBox_color.Value
                c = app.EditField_color.Value;
            else
                c = app.para_color.CheckedNodes(1).Tag;
            end

            %height
            if app.CheckBox_Height.Value
                h = app.EditField_Height.Value;
            else
                h = app.para_Height.CheckedNodes(1).Tag;
            end

            app.out.anime(app.net, ...
                          'style' , style,...
                          'size'  , s,...
                          'color' , c,...
                          'height', h );
        end

        % Value changed function: Language
        function LanguageValueChanged(app, event)
            switch app.Language.Value
                case '日本語'
                    app.title.Text         = 'Animation 条件設定';
                    app.Tab_Size.Title = 'サイズ';
                    app.Tab_color.Title = '色';
                    app.Tab_Height.Title = '高さ';
                    app.ParaWindow_Height.Title = '指標パラメータ';
                    app.ParaWIndow_Size.Title = '指標パラメータ';
                    app.ParaWindow_color.Title = '指標パラメータ';
                    app.ButtonGroup_Gstyle.Title = 'グラフのスタイル';
                    
                    w = {'潮流値'    ,'母線電圧','絶対値','偏角'   ,'実部'  ,'虚部' ,'母線電流','絶対値','偏角'  ,'実部'  ,'虚部'  ,'電力'  ,'有効電力','無効電力','皮相電力','力率'   ,'機器の状態','機器のパラメータ'};
                    c = {'powerflow','V'      ,'Vabs' ,'Vangle','Vreal','Vimag','I'     ,'Iabs' ,'Iangle','Ireal','Iimag','power','P'      ,'Q'      ,'S'      ,'Factor','X'       ,'Para' };
                    for i = 1:numel(w)
                        app.(['s',c{i}]).Text = w{i};
                        app.(['c',c{i}]).Text = w{i};
                        app.(['h',c{i}]).Text = w{i};
                    end

                case 'English'
                    app.title.Text         = 'Setting Animation Condition';
                    app.Tab_Size.Title = ' Size';
                    app.Tab_color.Title = 'Color';
                    app.Tab_Height.Title = 'Height';
                    app.ParaWindow_Height.Title = 'Parameter';
                    app.ParaWIndow_Size.Title = 'Parameter';
                    app.ParaWindow_color.Title = 'Parameter';
                    app.ButtonGroup_Gstyle.Title = 'Graph Style';
                    
                    w = {'power flow','Bus Voltage','abs' ,'angle' ,'Real Part','Imaginary Part','Bus Current','abs'  ,'angle' ,'Real Part','Imaginary Part','power','Active Power','Reactive Power','Apparent Power','Power Factor','Component State','Component Parameter'};
                    c = {'powerflow' ,'V'          ,'Vabs','Vangle','Vreal'    ,'Vimag'         ,'I'          ,'Iabs' ,'Iangle','Ireal'    ,'Iimag'         ,'power','P'           ,'Q'             ,'S'             ,'Factor'      ,'X'              ,'Para'               };
                    for i = 1:numel(w)
                        app.(['s',c{i}]).Text = w{i};
                        app.(['c',c{i}]).Text = w{i};
                        app.(['h',c{i}]).Text = w{i};
                    end
                    
            end
        end

        % Value changed function: CheckBox_Size
        function CheckBox_SizeValueChanged(app, event)
            value = app.CheckBox_Size.Value;
            app.EditField_Size.Editable = value;
            app.EditField_Size.BackgroundColor = app.textBackColor{value+1};
            app.para_Size.Enable = ~value;
        end

        % Value changed function: CheckBox_color
        function CheckBox_colorValueChanged(app, event)
            value = app.CheckBox_color.Value;
            app.EditField_color.Editable = value;
            app.EditField_color.BackgroundColor = app.textBackColor{value+1};
            app.para_color.Enable = ~value;
        end

        % Value changed function: CheckBox_Height
        function CheckBox_HeightValueChanged(app, event)
            value = app.CheckBox_Height.Value;
            app.EditField_Height.Editable = value;
            app.EditField_Height.BackgroundColor = app.textBackColor{value+1};
            app.para_Height.Enable = ~value;
        end

        % Callback function: para_Size
        function para_SizeCheckedNodesChanged(app, event)
            if ~strcmp(app.para_Size.SelectedNodes.Tag,'0')
                idx = app.para_Size.CheckedNodes==app.para_Size.SelectedNodes;
                app.para_Size.CheckedNodes(~idx) = [];
            else
                app.para_Size.CheckedNodes = [];
            end
        end

        % Callback function: para_color
        function para_colorCheckedNodesChanged(app, event)
            if ~strcmp(app.para_color.SelectedNodes.Tag,'0')
                idx = app.para_color.CheckedNodes==app.para_color.SelectedNodes;
                app.para_color.CheckedNodes(~idx) = [];
            else
                app.para_color.CheckedNodes = [];
            end
        end

        % Callback function: para_Height
        function para_HeightCheckedNodesChanged(app, event)
            if ~strcmp(app.para_Height.SelectedNodes.Tag,'0')
                idx = app.para_Height.CheckedNodes==app.para_Height.SelectedNodes;
                app.para_Height.CheckedNodes(~idx) = [];
            else
                app.para_Height.CheckedNodes = [];
            end
        end

        % Selection changed function: ButtonGroup_Gstyle
        function ButtonGroup_GstyleSelectionChanged(app, event)
            selectedButton = app.ButtonGroup_Gstyle.SelectedObject;
            app.Image_Gstyle.ImageSource = selectedButton.Tag;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Animation 条件設定';
            app.Language = uiswitch(app.UIFigure, 'slider');
            app.Language.Items = {'日本語', 'English'};
            app.Language.ValueChangedFcn = createCallbackFcn(app, @LanguageValueChanged, true);
            app.Language.Value = '日本語';
            app.ButtonGroup_Gstyle = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Gstyle.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_GstyleSelectionChanged, true);
            app.ButtonGroup_Gstyle.Title = 'グラフのスタイル';
            app.Image_Gstyle = uiimage(app.ButtonGroup_Gstyle);
            app.Image_Gstyle.ImageSource = 'GraphStyle1.png';
            app.GStyle3Button = uitogglebutton(app.ButtonGroup_Gstyle);
            app.GStyle3Button.Tag = 'GraphStyle3.png';
            app.GStyle3Button.Text = 'style3';
            app.GStyle2Button = uitogglebutton(app.ButtonGroup_Gstyle);
            app.GStyle2Button.Tag = 'GraphStyle2.png';
            app.GStyle2Button.Text = 'style2';
            app.GStyle1Button = uitogglebutton(app.ButtonGroup_Gstyle);
            app.GStyle1Button.Tag = 'GraphStyle1.png';
            app.GStyle1Button.Text = 'style1';
            app.GStyle1Button.Value = true;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_Size = uitab(app.TabGroup);
            app.Tab_Size.Title = 'サイズ';
            app.ParaWIndow_Size = uibuttongroup(app.Tab_Size);
            app.ParaWIndow_Size.Title = '指標パラメータ';
            app.para_Size = uitree(app.ParaWIndow_Size, 'checkbox');
            app.spowerflow = uitreenode(app.para_Size);
            app.spowerflow.Tag = '0';
            app.spowerflow.Text = '潮流値';
            app.sV = uitreenode(app.spowerflow);
            app.sV.Tag = '0';
            app.sV.Text = '母線電圧';
            app.sVabs = uitreenode(app.sV);
            app.sVabs.Tag = 'Vabs';
            app.sVabs.Text = '絶対値';
            app.sVangle = uitreenode(app.sV);
            app.sVangle.Tag = 'Vangle';
            app.sVangle.Text = '偏角';
            app.sVreal = uitreenode(app.sV);
            app.sVreal.Tag = 'Vreal';
            app.sVreal.Text = '実部';
            app.sVimag = uitreenode(app.sV);
            app.sVimag.Tag = 'Vimag';
            app.sVimag.Text = '虚部';
            app.sI = uitreenode(app.spowerflow);
            app.sI.Tag = '0';
            app.sI.Text = '母線電流';
            app.sIabs = uitreenode(app.sI);
            app.sIabs.Tag = 'Iabs';
            app.sIabs.Text = '絶対値';
            app.sIangle = uitreenode(app.sI);
            app.sIangle.Tag = 'Iangle';
            app.sIangle.Text = '偏角';
            app.sIreal = uitreenode(app.sI);
            app.sIreal.Tag = 'Ireal';
            app.sIreal.Text = '実部';
            app.sIimag = uitreenode(app.sI);
            app.sIimag.Tag = 'Iimag';
            app.sIimag.Text = '虚部';
            app.spower = uitreenode(app.spowerflow);
            app.spower.Tag = '0';
            app.spower.Text = '電力';
            app.sP = uitreenode(app.spower);
            app.sP.Tag = 'P';
            app.sP.Text = '有効電力';
            app.sQ = uitreenode(app.spower);
            app.sQ.Tag = 'Q';
            app.sQ.Text = '無効電力';
            app.sS = uitreenode(app.spower);
            app.sS.Tag = 'S';
            app.sS.Text = '皮相電力';
            app.sFactor = uitreenode(app.spower);
            app.sFactor.Tag = 'Factor';
            app.sFactor.Text = '力率';
            app.sX = uitreenode(app.para_Size);
            app.sX.Tag = '0';
            app.sX.Text = '機器の状態';
            app.sPara = uitreenode(app.para_Size);
            app.sPara.Tag = '0';
            app.sPara.Text = '機器のパラメータ';
            % Assign Checked Nodes
            app.para_Size.CheckedNodesChangedFcn = createCallbackFcn(app, @para_SizeCheckedNodesChanged, true);
            app.CheckBox_Size = uicheckbox(app.Tab_Size);
            app.CheckBox_Size.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SizeValueChanged, true);
            app.CheckBox_Size.Text = 'constant value';
            app.ValueLabel = uilabel(app.Tab_Size);
            app.ValueLabel.Text = '→';
            app.EditField_Size = uieditfield(app.Tab_Size, 'numeric');
            app.Tab_color = uitab(app.TabGroup);
            app.Tab_color.Title = '色';
            app.ParaWindow_color = uibuttongroup(app.Tab_color);
            app.ParaWindow_color.Title = '指標パラメータ';
            app.para_color = uitree(app.ParaWindow_color, 'checkbox');
            app.cpowerflow = uitreenode(app.para_color);
            app.cpowerflow.Tag = '0';
            app.cpowerflow.Text = '潮流値';
            app.cV = uitreenode(app.cpowerflow);
            app.cV.Tag = '0';
            app.cV.Text = '母線電圧';
            app.cVabs = uitreenode(app.cV);
            app.cVabs.Tag = 'Vabs';
            app.cVabs.Text = '絶対値';
            app.cVangle = uitreenode(app.cV);
            app.cVangle.Tag = 'Vangle';
            app.cVangle.Text = '偏角';
            app.cVreal = uitreenode(app.cV);
            app.cVreal.Tag = 'Vreal';
            app.cVreal.Text = '実部';
            app.cVimag = uitreenode(app.cV);
            app.cVimag.Tag = 'Vimag';
            app.cVimag.Text = '虚部';
            app.cI = uitreenode(app.cpowerflow);
            app.cI.Tag = '0';
            app.cI.Text = '母線電流';
            app.cIabs = uitreenode(app.cI);
            app.cIabs.Tag = 'Iabs';
            app.cIabs.Text = '絶対値';
            app.cIangle = uitreenode(app.cI);
            app.cIangle.Tag = 'Iangle';
            app.cIangle.Text = '偏角';
            app.cIreal = uitreenode(app.cI);
            app.cIreal.Tag = 'Ireal';
            app.cIreal.Text = '実部';
            app.cIimag = uitreenode(app.cI);
            app.cIimag.Tag = 'Iimag';
            app.cIimag.Text = '虚部';
            app.cpower = uitreenode(app.cpowerflow);
            app.cpower.Tag = '0';
            app.cpower.Text = '電力';
            app.cP = uitreenode(app.cpower);
            app.cP.Tag = 'P';
            app.cP.Text = '有効電力';
            app.cQ = uitreenode(app.cpower);
            app.cQ.Tag = 'Q';
            app.cQ.Text = '無効電力';
            app.cS = uitreenode(app.cpower);
            app.cS.Tag = 'S';
            app.cS.Text = '皮相電力';
            app.cFactor = uitreenode(app.cpower);
            app.cFactor.Tag = 'Factor';
            app.cFactor.Text = '力率';
            app.cX = uitreenode(app.para_color);
            app.cX.Tag = '0';
            app.cX.Text = '機器の状態';
            app.cPara = uitreenode(app.para_color);
            app.cPara.Tag = '0';
            app.cPara.Text = '機器のパラメータ';
            % Assign Checked Nodes
            app.para_color.CheckedNodesChangedFcn = createCallbackFcn(app, @para_colorCheckedNodesChanged, true);
            app.CheckBox_color = uicheckbox(app.Tab_color);
            app.CheckBox_color.ValueChangedFcn = createCallbackFcn(app, @CheckBox_colorValueChanged, true);
            app.CheckBox_color.Text = 'constant value';
            app.EditField_2Label = uilabel(app.Tab_color);
            app.EditField_2Label.Text = '→';
            app.EditField_color = uieditfield(app.Tab_color, 'numeric');
            app.Tab_Height = uitab(app.TabGroup);
            app.Tab_Height.Title = '高さ';
            app.ParaWindow_Height = uibuttongroup(app.Tab_Height);
            app.ParaWindow_Height.Title = '指標パラメータ';
            app.para_Height = uitree(app.ParaWindow_Height, 'checkbox');
            app.hpowerflow = uitreenode(app.para_Height);
            app.hpowerflow.Tag = '0';
            app.hpowerflow.Text = '潮流値';
            app.hV = uitreenode(app.hpowerflow);
            app.hV.Tag = '0';
            app.hV.Text = '母線電圧';
            app.hVabs = uitreenode(app.hV);
            app.hVabs.Tag = 'Vabs';
            app.hVabs.Text = '絶対値';
            app.hVangle = uitreenode(app.hV);
            app.hVangle.Tag = 'Vangle';
            app.hVangle.Text = '偏角';
            app.hVreal = uitreenode(app.hV);
            app.hVreal.Tag = 'Vreal';
            app.hVreal.Text = '実部';
            app.hVimag = uitreenode(app.hV);
            app.hVimag.Tag = 'Vimag';
            app.hVimag.Text = '虚部';
            app.hI = uitreenode(app.hpowerflow);
            app.hI.Tag = '0';
            app.hI.Text = '母線電流';
            app.hIabs = uitreenode(app.hI);
            app.hIabs.Tag = 'Iabs';
            app.hIabs.Text = '絶対値';
            app.hIangle = uitreenode(app.hI);
            app.hIangle.Tag = 'Iangle';
            app.hIangle.Text = '偏角';
            app.hIreal = uitreenode(app.hI);
            app.hIreal.Tag = 'Ireal';
            app.hIreal.Text = '実部';
            app.hIimag = uitreenode(app.hI);
            app.hIimag.Tag = 'Iimag';
            app.hIimag.Text = '虚部';
            app.hpower = uitreenode(app.hpowerflow);
            app.hpower.Tag = '0';
            app.hpower.Text = '電力';
            app.hP = uitreenode(app.hpower);
            app.hP.Tag = 'P';
            app.hP.Text = '有効電力';
            app.hQ = uitreenode(app.hpower);
            app.hQ.Tag = 'Q';
            app.hQ.Text = '無効電力';
            app.hS = uitreenode(app.hpower);
            app.hS.Tag = 'S';
            app.hS.Text = '皮相電力';
            app.hFactor = uitreenode(app.hpower);
            app.hFactor.Tag = 'Factor';
            app.hFactor.Text = '力率';
            app.hX = uitreenode(app.para_Height);
            app.hX.Tag = '0';
            app.hX.Text = '機器の状態';
            app.hPara = uitreenode(app.para_Height);
            app.hPara.Tag = '0';
            app.hPara.Text = '機器のパラメータ';
            % Assign Checked Nodes
            app.para_Height.CheckedNodesChangedFcn = createCallbackFcn(app, @para_HeightCheckedNodesChanged, true);
            app.CheckBox_Height = uicheckbox(app.Tab_Height);
            app.CheckBox_Height.ValueChangedFcn = createCallbackFcn(app, @CheckBox_HeightValueChanged, true);
            app.CheckBox_Height.Text = 'constant value';
            app.EditField_3Label = uilabel(app.Tab_Height);
            app.EditField_3Label.Text = '→';
            app.EditField_Height = uieditfield(app.Tab_Height, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UIanime(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end