classdef UIplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        angle_unwrap    matlab.ui.control.CheckBox
        command         matlab.ui.control.CheckBox
        LineWidthLabel  matlab.ui.control.Label
        LineWidth       matlab.ui.control.NumericEditField
        Language        matlab.ui.control.Switch
        title           matlab.ui.control.Label
        legend          matlab.ui.control.CheckBox
        para_window     matlab.ui.container.ButtonGroup
        para            matlab.ui.container.CheckBoxTree
        powerflow       matlab.ui.container.TreeNode
        V               matlab.ui.container.TreeNode
        Vabs            matlab.ui.container.TreeNode
        Vangle          matlab.ui.container.TreeNode
        Vreal           matlab.ui.container.TreeNode
        Vimag           matlab.ui.container.TreeNode
        I               matlab.ui.container.TreeNode
        Iabs            matlab.ui.container.TreeNode
        Iangle          matlab.ui.container.TreeNode
        Ireal           matlab.ui.container.TreeNode
        Iimag           matlab.ui.container.TreeNode
        power           matlab.ui.container.TreeNode
        P               matlab.ui.container.TreeNode
        Q               matlab.ui.container.TreeNode
        S               matlab.ui.container.TreeNode
        Factor          matlab.ui.container.TreeNode
        X               matlab.ui.container.TreeNode
        bus_window      matlab.ui.container.ButtonGroup
        specify         matlab.ui.control.EditField
        specify_tf      matlab.ui.control.CheckBox
        bus_idx         matlab.ui.container.CheckBoxTree
        all_bus         matlab.ui.container.TreeNode
        bus_type        matlab.ui.container.TreeNode
        bus_PV          matlab.ui.container.TreeNode
        bus_PQ          matlab.ui.container.TreeNode
        bus_slack       matlab.ui.container.TreeNode
        all_component   matlab.ui.container.TreeNode
        plotButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        out
        component
        state
        check_box_switch
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, out)
            app.out = out;
            
            app.legend.Value = false;
            app.LineWidth.Value = 2;
            app.Language.Value = '日本語';

            net_data = app.out.net_data;
            % Create checkbox for each component
            clist = net_data.component{:,'class'};
            clist = unique(clist);
            for i = 1:numel(clist)
                app.component{i} = uitreenode(app.all_component);
                app.component{i}.Text = clist(i);
                app.component{i}.Tag  = clist(i);
            end

            % Create checkbox for each state of system
            uni_state = net_data.x_equilibrium.component.Properties.VariableNames;
            for  i = 1:numel(uni_state)
                app.state{i} = uitreenode(app.X);
                app.state{i}.Text = uni_state{i};
                app.state{i}.Tag  = uni_state{i};
            end
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            paralist = app.para.CheckedNodes;
            data_para     = tools.arrayfun(@(idx) paralist(idx).Tag,1:numel(paralist));

            if app.specify_tf.Value
                data_bus_idx = str2num(app.specify.Value);
            else
                buslist  = app.bus_idx.CheckedNodes;
                data_bus_idx  = tools.arrayfun(@(idx) buslist(idx).Tag,1:numel(buslist));
            end
            app.out.plot('bus_idx'     , data_bus_idx,....
                         'para'        , data_para,...
                         'legend'      , app.legend.Value,...
                         'disp_command', app.command.Value,...
                         'LineWidth'   , app.LineWidth.Value, ...
                         'angle_unwrap', app.angle_unwrap.Value)
        end

        % Value changed function: Language
        function LanguageValueChanged(app, event)
            switch app.Language.Value
                case '日本語'
                    app.title.Text         = 'プロット条件設定';
                    app.bus_window.Title   = 'プロット対象母線';
                    app.all_bus.Text       = '全ての母線';
                    app.bus_type.Text      = '母線の種類で選択';
                    app.bus_PV.Text        = 'PV母線';
                    app.bus_PQ.Text        = 'PQ母線';
                    app.bus_slack.Text     = 'slack母線';
                    app.all_component.Text = '機器の種類で選択';
                    app.specify_tf.Text    = '特定の母線のみ';
                    app.para_window.Title  = 'プロット対象パラメータ';
                    app.powerflow.Text     = '潮流値';
                    app.V.Text             = '母線電圧';
                    app.Vabs.Text          = '絶対値';
                    app.Vangle.Text        = '偏角';
                    app.Vreal.Text         = '実部';
                    app.Vimag.Text         = '虚部';
                    app.I.Text             = '母線電圧';
                    app.Iabs.Text          = '絶対値';
                    app.Iangle.Text        = '偏角';
                    app.Ireal.Text         = '実部';
                    app.Iimag.Text         = '虚部';
                    app.power.Text         = '電力';
                    app.P.Text             = '有効電力';
                    app.Q.Text             = '無効電力';
                    app.S.Text             = '皮相電力';
                    app.Factor.Text             = '力率';
                    app.X.Text             = '機器の状態';
                    app.legend.Text        = ':凡例あり';
                    app.command.Text       = ':コマン出力';
                    app.angle_unwrap.Text  = ':位相角のシフト';
                    app.LineWidthLabel.Text= ':線の太さ';

                case 'English'
                    app.title.Text         = 'Setting Plot Conditions';
                    app.bus_window.Title   = 'bus index to plot';
                    app.all_bus.Text       = 'all bus';
                    app.bus_type.Text      = 'bus type';
                    app.bus_PV.Text        = 'PVbus';
                    app.bus_PQ.Text        = 'PQbus';
                    app.bus_slack.Text     = 'slackbus';
                    app.all_component.Text = 'component type';
                    app.specify_tf.Text    = 'specify';
                    app.para_window.Title  = 'parameter to plot';
                    app.powerflow.Text     = 'power flow';
                    app.V.Text             = 'bus voltage';
                    app.Vabs.Text          = 'absolute';
                    app.Vangle.Text        = 'angle';
                    app.Vreal.Text         = 'real part';
                    app.Vimag.Text         = 'imaginary part';
                    app.I.Text             = 'bus current';
                    app.Iabs.Text          = 'absolute';
                    app.Iangle.Text        = 'angle';
                    app.Ireal.Text         = 'real part';
                    app.Iimag.Text         = 'imaginary part';
                    app.power.Text         = 'power';
                    app.P.Text             = 'active power';
                    app.Q.Text             = 'reactive power';
                    app.S.Text             = 'apparent power';
                    app.Factor.Text        = 'Power Factor';
                    app.X.Text             = 'state of component';
                    app.legend.Text        = ':legend';
                    app.command.Text       = ':command Output';
                    app.angle_unwrap.Text  = ':Shift angles';
                    app.LineWidthLabel.Text= ':LineWidth';
                    
            end
        end

        % Callback function: bus_idx
        function bus_idxCheckedNodesChanged(app, event)
            app.specify_tf.Value = false;
            app.specify.BackgroundColor = [0.65,0.65,0.65];
        end

        % Value changed function: specify_tf
        function specify_tfValueChanged(app, event)
            app.bus_idx.CheckedNodes = [];
            if app.specify_tf.Value
                app.specify.BackgroundColor = [0.73,0.91,0.98];
            else
                app.specify.BackgroundColor = [0.65,0.65,0.65];
            end
        end

        % Value changed function: specify
        function specifyValueChanged(app, event)
            value = app.specify.Value;
            if numel(str2num(value))==0
                app.specify.FontColor = [1.00,0.00,0.00];
                app.specify.Value = 'e.g. [1,2,3] or 1:3';
            else
                app.specify.FontColor = [0.15,0.15,0.15];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.bus_window = uibuttongroup(app.UIFigure);
            app.bus_window.Title = 'プロット対象母線';
            app.bus_idx = uitree(app.bus_window, 'checkbox');
            app.all_bus = uitreenode(app.bus_idx);
            app.all_bus.Tag = 'all_bus';
            app.all_bus.Text = '全ての母線';
            app.bus_type = uitreenode(app.all_bus);
            app.bus_type.Tag = 'all_bus';
            app.bus_type.Text = '母線の種類で選択';
            app.bus_PV = uitreenode(app.bus_type);
            app.bus_PV.Tag = 'bus_PV';
            app.bus_PV.Text = 'PV母線';
            app.bus_PQ = uitreenode(app.bus_type);
            app.bus_PQ.Tag = 'bus_PQ';
            app.bus_PQ.Text = 'PQ母線';
            app.bus_slack = uitreenode(app.bus_type);
            app.bus_slack.Tag = 'bus_slack';
            app.bus_slack.Text = 'slack母線';
            app.all_component = uitreenode(app.all_bus);
            app.all_component.Tag = 'all_bus';
            app.all_component.Text = '機器の種類で選択';
            % Assign Checked Nodes
            app.bus_idx.CheckedNodesChangedFcn = createCallbackFcn(app, @bus_idxCheckedNodesChanged, true);
            app.specify_tf = uicheckbox(app.bus_window);
            app.specify_tf.ValueChangedFcn = createCallbackFcn(app, @specify_tfValueChanged, true);
            app.specify_tf.Text = '特定の母線のみ';
            app.specify = uieditfield(app.bus_window, 'text');
            app.specify.ValueChangedFcn = createCallbackFcn(app, @specifyValueChanged, true);
            app.specify.Value = 'e.g. [1,2,3] or 1:3';
            app.para_window = uibuttongroup(app.UIFigure);
            app.para_window.Title = 'プロット対象パラメータ';
            app.para = uitree(app.para_window, 'checkbox');
            app.powerflow = uitreenode(app.para);
            app.powerflow.Tag = 'powerflow';
            app.powerflow.Text = '潮流値';
            app.V = uitreenode(app.powerflow);
            app.V.Tag = 'V';
            app.V.Text = '母線電圧';
            app.Vabs = uitreenode(app.V);
            app.Vabs.Tag = 'Vabs';
            app.Vabs.Text = '絶対値';
            app.Vangle = uitreenode(app.V);
            app.Vangle.Tag = 'Vangle';
            app.Vangle.Text = '偏角';
            app.Vreal = uitreenode(app.V);
            app.Vreal.Tag = 'Vreal';
            app.Vreal.Text = '実部';
            app.Vimag = uitreenode(app.V);
            app.Vimag.Tag = 'Vimag';
            app.Vimag.Text = '虚部';
            app.I = uitreenode(app.powerflow);
            app.I.Tag = 'I';
            app.I.Text = '母線電流';
            app.Iabs = uitreenode(app.I);
            app.Iabs.Tag = 'Iabs';
            app.Iabs.Text = '絶対値';
            app.Iangle = uitreenode(app.I);
            app.Iangle.Tag = 'Iangle';
            app.Iangle.Text = '偏角';
            app.Ireal = uitreenode(app.I);
            app.Ireal.Tag = 'Ireal';
            app.Ireal.Text = '実部';
            app.Iimag = uitreenode(app.I);
            app.Iimag.Tag = 'Iimag';
            app.Iimag.Text = '虚部';
            app.power = uitreenode(app.powerflow);
            app.power.Tag = 'power';
            app.power.Text = '電力';
            app.P = uitreenode(app.power);
            app.P.Tag = 'P';
            app.P.Text = '有効電力';
            app.Q = uitreenode(app.power);
            app.Q.Tag = 'Q';
            app.Q.Text = '無効電力';
            app.S = uitreenode(app.power);
            app.S.Tag = 'S';
            app.S.Text = '皮相電力';
            app.Factor = uitreenode(app.power);
            app.Factor.Tag = 'Factor';
            app.Factor.Text = '力率';
            app.X = uitreenode(app.para);
            app.X.Tag = 'X';
            app.X.Text = '機器の状態';
            app.legend = uicheckbox(app.UIFigure);
            app.legend.Text = ':凡例あり';
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'プロット条件設定';
            app.Language = uiswitch(app.UIFigure, 'slider');
            app.Language.Items = {'日本語', 'English'};
            app.Language.ValueChangedFcn = createCallbackFcn(app, @LanguageValueChanged, true);
            app.Language.Value = '日本語';
            app.LineWidth = uieditfield(app.UIFigure, 'numeric');
            app.LineWidthLabel = uilabel(app.UIFigure);
            app.LineWidthLabel.Text = ': 線の太さ';
            app.command = uicheckbox(app.UIFigure);
            app.command.Text = ':コマンド出力';
            app.angle_unwrap = uicheckbox(app.UIFigure);
            app.angle_unwrap.Text = ':位相角パラメータの整理';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UIplot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end