classdef animator_controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        QuitButton                matlab.ui.control.Button
        GraphStyleDropDown        matlab.ui.control.DropDown
        GraphStyleDropDownLabel   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        speedEditField            matlab.ui.control.NumericEditField
        speedLabel                matlab.ui.control.Label
        playButton                matlab.ui.control.Button
        AnimationControllerLabel  matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        Button                    matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        animator % Description
        now_play = false;
        ToBeStop = false;
    end

    methods
        function play(app,tidx)
            app.animator.update_graph(tidx,1);
            
            ntime = numel(app.animator.time_list);
            
            while ~app.ToBeStop
                tidx = tidx+app.speedEditField.Value;
                if tidx>ntime
                    app.Slider.Value = app.Slider.Limits(1);
                    break
                end
                app.Slider.Value = app.animator.time_list(tidx);
                app.animator.update_graph(tidx,1);
                drawnow
            end
            app.playButtonPushed([])
            app.ToBeStop = false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, animator)
            app.animator = animator;
            app.Slider.Limits = animator.out.t([1,end]);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.animator.current_time = app.Slider.Value;
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            if app.now_play
                app.ToBeStop = true;
                app.now_play = false;
                app.playButton.BackgroundColor = [0.00,0.45,0.74];
                app.playButton.Text = 'Play';
            else
                app.now_play = true;
                app.playButton.BackgroundColor = [0.85,0.33,0.10];
                app.playButton.Text = 'Pause';
                tidx_start = find(app.animator.time_list<=app.Slider.Value,1,'last');
                app.play(tidx_start);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isgraphics(app.animator.fig)
                close(app.animator.fig)
            end
            delete(app)
        end

        % Value changed function: speedEditField
        function speedEditFieldValueChanged(app, event)
            value = app.speedEditField.Value;
            value = round(value);
            if value<1
                value = 1;
            end
            app.speedEditField.Value = value;
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            app.UIFigureCloseRequest;
        end

        % Value changed function: GraphStyleDropDown
        function GraphStyleDropDownValueChanged(app, event)
            app.animator.style = app.GraphStyleDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.AnimationControllerLabel = uilabel(app.UIFigure);
            app.AnimationControllerLabel.Text = 'Animation Controller';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.speedLabel = uilabel(app.UIFigure);
            app.speedLabel.Text = 'speed';
            app.speedEditField = uieditfield(app.UIFigure, 'numeric');
            app.speedEditField.ValueChangedFcn = createCallbackFcn(app, @speedEditFieldValueChanged, true);
            app.speedEditField.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '×';
            app.GraphStyleDropDownLabel = uilabel(app.UIFigure);
            app.GraphStyleDropDownLabel.Text = 'Graph Style : ';
            app.GraphStyleDropDown = uidropdown(app.UIFigure);
            app.GraphStyleDropDown.Items = {'style1', 'style2', 'style3'};
            app.GraphStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphStyleDropDownValueChanged, true);
            app.GraphStyleDropDown.Value = 'style1';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animator_controller(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end