classdef LiveSimulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LeftPanel                      matlab.ui.container.Panel
        NetworkGraphLabel              matlab.ui.control.Label
        LIVESimulationLabel            matlab.ui.control.Label
        QUITButton                     matlab.ui.control.Button
        STOPButton                     matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        SimulationSettingTab           matlab.ui.container.Tab
        Tab_SImulationSetting          matlab.ui.container.TabGroup
        Tab_component                  matlab.ui.container.Tab
        SelectComponentListBox         matlab.ui.control.ListBox
        SelectComponentLabel           matlab.ui.control.Label
        Panel_component                matlab.ui.container.Panel
        u_equilibriumLabel             matlab.ui.control.Label
        parameterLabel                 matlab.ui.control.Label
        Tab_comp_ust                   matlab.ui.control.Table
        Tab_comp_parameter             matlab.ui.control.Table
        Lamp_comp_parallel             matlab.ui.control.Lamp
        Switch_comp_parallel           matlab.ui.control.ToggleSwitch
        parallelSwitchLabel            matlab.ui.control.Label
        Tab_controller                 matlab.ui.container.Tab
        SelectControllerLabel          matlab.ui.control.Label
        SelectControllerListBox        matlab.ui.control.ListBox
        Panel_controller               matlab.ui.container.Panel
        GraphmarkingButtonGroup        matlab.ui.container.ButtonGroup
        index_inputButton              matlab.ui.control.ToggleButton
        index_observeButton            matlab.ui.control.ToggleButton
        parameterLabel_2               matlab.ui.control.Label
        Tab_con_parameter              matlab.ui.control.Table
        Lamp_con_parallel              matlab.ui.control.Lamp
        Switch_con_parallel            matlab.ui.control.ToggleSwitch
        parallelSwitch_2Label          matlab.ui.control.Label
        Tab_groundfault                matlab.ui.container.Tab
        TextBox_fault_busidx           matlab.ui.control.EditField
        BusindexwheregroundfaultoccursEditFieldLabel  matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        TextBox_fault_time             matlab.ui.control.EditField
        LengthoftimeofoccurrenceLabel  matlab.ui.control.Label
        Button_fault                   matlab.ui.control.Button
        GraphPropertiesTab             matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        ComponentNodeTab               matlab.ui.container.Tab
        Tab_GraphComponent             matlab.ui.control.Table
        BusNodeTab                     matlab.ui.container.Tab
        Tab_GraphBus                   matlab.ui.control.Table
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        ResponsePlotpropertiesTab      matlab.ui.container.Tab
        para_window                    matlab.ui.container.ButtonGroup
        para                           matlab.ui.container.CheckBoxTree
        powerflow                      matlab.ui.container.TreeNode
        V                              matlab.ui.container.TreeNode
        Vabs                           matlab.ui.container.TreeNode
        Vangle                         matlab.ui.container.TreeNode
        Vreal                          matlab.ui.container.TreeNode
        Vimag                          matlab.ui.container.TreeNode
        I                              matlab.ui.container.TreeNode
        Iabs                           matlab.ui.container.TreeNode
        Iangle                         matlab.ui.container.TreeNode
        Ireal                          matlab.ui.container.TreeNode
        Iimag                          matlab.ui.container.TreeNode
        power                          matlab.ui.container.TreeNode
        P                              matlab.ui.container.TreeNode
        Q                              matlab.ui.container.TreeNode
        S                              matlab.ui.container.TreeNode
        Factor                         matlab.ui.container.TreeNode
        X                              matlab.ui.container.TreeNode
        bus_window                     matlab.ui.container.ButtonGroup
        specify                        matlab.ui.control.EditField
        specify_tf                     matlab.ui.control.CheckBox
        bus_idx                        matlab.ui.container.CheckBoxTree
        all_bus                        matlab.ui.container.TreeNode
        bus_type                       matlab.ui.container.TreeNode
        bus_PV                         matlab.ui.container.TreeNode
        bus_PQ                         matlab.ui.container.TreeNode
        bus_slack                      matlab.ui.container.TreeNode
        all_component                  matlab.ui.container.TreeNode
        STARTButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Button                         matlab.ui.control.Button
        Axes_graph                     matlab.ui.control.UIAxes
        Axes_response                  matlab.ui.control.UIAxes
    end

    
    properties(Access = private)
        net % power_network class
        data 
        plot_component
        plot_state

        setting_component
        setting_controller

        simulator
        Events_simulation = false;

        GraphData
        GraphMarker = {};
        plot_op = {'MarkerFaceColor','none','MarkerEdgeColor',[1,0,0],'LineStyle','none','LineWidth',2};

        color_on  = [0.00,1.00,0.00];
        color_off = [0.80,0.80,0.80];
    end

    methods
        refresh_response_setting(app)

        function [value,isterminal,direction] = myEventsFcn(app,~,~)
            value = app.Events_simulation;
            isterminal = 1;
            direction  = 0;
            if value
                app.Events_simulation = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, net, varargin)
            app.net = net;
            net_data = net.information('do_report',false);            

            % Create checkbox for each component
            clist_all = tools.vcellfun(@(b) string(b.component.Tag), net.a_bus);
            clist = unique(clist_all);
            for i = 1:numel(clist)
                app.plot_component{i} = uitreenode(app.all_component);
                app.plot_component{i}.Text = clist(i);
                app.plot_component{i}.Tag  = clist(i);
            end
            app.SelectComponentListBox.Items  = clist_all + (1:numel(clist_all))';
            app.SelectComponentListBox.ItemsData = tools.cellfun(@(b) b.component, net.a_bus);

            con_data = [net_data.controller_local;net_data.controller_global];
            if ~isempty(con_data)
                clist_all = con_data{:,'class'};
                app.SelectControllerListBox.Items = (1:numel(clist_all))'+"."+clist_all;
                app.SelectControllerListBox.ItemsData = [net.a_controller_local;net.a_controller_global];
            end

            % Create checkbox for each state of system
            uni_state = net_data.x_equilibrium.component.Properties.VariableNames;
            for  i = 1:numel(uni_state)
                app.plot_state{i} = uitreenode(app.X);
                app.plot_state{i}.Text = uni_state{i};
                app.plot_state{i}.Tag  = uni_state{i};
            end

            s = uistyle('HorizontalAlignment','center'); % 中央寄せのスタイルを作成
            % スタイルをテーブルに適用
            addStyle(app.Tab_comp_parameter,s) 
            addStyle(app.Tab_comp_ust,s)
            addStyle(app.Tab_con_parameter,s) 

            app.data = net_data;

            app.GraphData = supporters.for_graph.map_forUI(net,app.Axes_graph);

            % app.SelectControllerListBoxValueChanged_old([])
            app.simulator =  supporters.for_simulate.solver(net,[0,inf],'do_report',false,varargin{:});
            app.simulator.additional_odeopt = {'Events',@(t,y)app.myEventsFcn(t,y)};

            %app.simulator.run;
            app.Tab_SImulationSettingSelectionChanged;

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            supporters.debugger.count_line_of_directory
        end

        % Value changed function: specify
        function specifyValueChanged(app, event)
            value = app.specify.Value;
            if numel(str2num(value))==0%#ok
                app.specify.FontColor = [1.00,0.00,0.00];
                app.specify.Value = 'e.g. [1,2,3] or 1:3';
            else
                app.specify.FontColor = [0.15,0.15,0.15];
            end
            app.refresh_response_setting
        end

        % Callback function: bus_idx
        function bus_idxCheckedNodesChanged(app, event)
            app.specify_tf.Value = false;
            app.specify.BackgroundColor = [0.65,0.65,0.65];
            app.refresh_response_setting
        end

        % Value changed function: specify_tf
        function specify_tfValueChanged(app, event)
            app.bus_idx.CheckedNodes = [];
            if app.specify_tf.Value
                app.specify.BackgroundColor = [0.73,0.91,0.98];
            else
                app.specify.BackgroundColor = [0.65,0.65,0.65];
            end
            app.refresh_response_setting
        end

        % Value changed function: SelectComponentListBox
        function SelectComponentListBoxValueChanged(app, event)
            c = app.SelectComponentListBox.Value;
            app.Lamp_comp_parallel.Color = app.(['color_',c.parallel]);
            app.Panel_component.Title = string(c.Tag)+" @ Bus"+app.SelectComponentListBox.ValueIndex;
            
            app.Tab_comp_parameter.RowName    = c.parameter.Properties.VariableNames;
            app.Tab_comp_parameter.ColumnName = {'Value'};
            if isempty(c.parameter)
                app.Tab_comp_parameter.Data       = string(nan);
            else
                app.Tab_comp_parameter.Data       = string(num2str(c.parameter{1,:}.'));
            end
            app.Tab_comp_parameter.ColumnEditable = true;

            app.Tab_comp_ust.RowName    = c.get_port_name;
            app.Tab_comp_ust.ColumnName = {'Value'}; 
            app.Tab_comp_ust.Data       = c.u_equilibrium(:);
            app.Tab_comp_ust.ColumnEditable = true;

            app.Switch_comp_parallel.Value = c.parallel;

            c = app.GraphData.a_component{app.SelectComponentListBox.ValueIndex};
            app.GraphMarker{1}.XData(1) = c.XData;
            app.GraphMarker{1}.YData(1) = c.YData;
            app.GraphMarker{1}.ZData(1) = c.ZData;
            app.GraphMarker{1}.Marker = c.marker;
            app.GraphMarker{1}.MarkerSize(1) = c.size+2;
        end

        % Value changed function: SelectControllerListBox
        function SelectControllerListBoxValueChanged(app, event)
            c = app.SelectControllerListBox.Value;
            app.Lamp_con_parallel.Color = app.(['color_',c.parallel]);
            app.Panel_controller.Title = app.SelectComponentListBox.ValueIndex +"."+ string(class(c));
            
            app.Tab_con_parameter.RowName    = c.parameter.Properties.VariableNames;
            app.Tab_con_parameter.ColumnName = {'Value'};
            if isempty( c.parameter )
                app.Tab_comp_parameter.Data       = string(nan);
            else
                app.Tab_comp_parameter.Data       = string(num2str(c.parameter{1,:}.'));
            end
            app.Tab_con_parameter.ColumnEditable = [false,true];

            app.Switch_comp_parallel.Value = c.parallel;

            app.GraphmarkingButtonGroupSelectionChanged
        end

        % Cell edit callback: Tab_comp_parameter
        function Tab_comp_parameterCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newData = str2double(newData);
            if isnan(newData)
                app.Tab_comp_parameter.Data(indices(1),indices(2)) = event.PreviousData;
            else
                c = app.SelectComponentListBox.Value;
                c.parameter{:,indices(1)} = newData;
            end
        end

        % Cell edit callback: Tab_con_parameter
        function Tab_con_parameterCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newData = str2double(newData);
            if isnan(newData)
                app.Tab_con_parameter.Data(indices(1),indices(2)) = event.PreviousData;
            else
                c = app.SelectControllerListBox.Value;
                c.parameter{:,indices(1)} = newData;
            end
        end

        % Cell edit callback: Tab_comp_ust
        function Tab_comp_ustCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            newData = str2double(newData);
            if isnan(newData)
                app.Tab_comp_ust.Data(indices(1),indices(2)) = event.PreviousData;
            else
                c = app.SelectComponentListBox.Value;
                c.u_equilibrium(indices(1)) = newData;
            end
        end

        % Value changed function: Switch_comp_parallel
        function Switch_comp_parallelValueChanged(app, event)
            value = app.Switch_comp_parallel.Value;
            
            idx  = app.SelectComponentListBox.ValueIndex;
            
            switch value
                case 'on'
                    app.Lamp_comp_parallel.Color = app.color_on;
                    app.GraphData.a_component{idx}.color = app.color_on;
                    app.SelectComponentListBox.Value.connect;
                    app.simulator.additional_I0bus = setdiff(app.simulator.additional_I0bus, idx);
                case 'off'
                    app.Lamp_comp_parallel.Color    = app.color_off;
                    app.GraphData.a_component{idx}.color = app.color_off;
                    app.SelectComponentListBox.Value.disconnect
                    app.simulator.additional_I0bus = union(app.simulator.additional_I0bus, idx);
            end
            app.Events_simulation = true;

        end

        % Value changed function: Switch_con_parallel
        function Switch_con_parallelValueChanged(app, event)
            value = app.Switch_con_parallel.Value;
            switch value
                case 'on'
                    app.Lamp_con_parallel.Color = app.color_on;
                    app.SelectControllerListBox.Value.connect
                case 'off'
                    app.Lamp_con_parallel.Color = app.color_off;
                    app.SelectControllerListBox.Value.disconnect
            end
            warning('controllerのon/off切り替えは実装中です...')
        end

        % Value changed function: TextBox_fault_busidx
        function TextBox_fault_busidxValueChanged(app, event)
            value = app.TextBox_fault_busidx.Value;
            if isempty(str2num(value))%#ok
                app.TextBox_fault_busidx.Value = event.PreviousData;
            end
        end

        % Value changed function: TextBox_fault_time
        function TextBox_fault_timeValueChanged(app, event)
            value = app.TextBox_fault_time.Value;
            if isempty(str2num(value))%#ok
                app.TextBox_fault_time.Value = event.PreviousData;
            end
        end

        % Button pushed function: Button_fault
        function Button_faultPushed(app, event)
            idx = str2num( app.TextBox_fault_busidx.Value );%#ok
            time    = str2num( app.TextBox_fault_time.Value   );%#ok

            app.simulator.additional_V0bus = union(app.simulator.additional_V0bus, idx);
            app.Events_simulation = true;
        end

        % Selection change function: Tab_SImulationSetting
        function Tab_SImulationSettingSelectionChanged(app, event)
            selectedTab = app.Tab_SImulationSetting.SelectedTab;
            cellfun(@(p) delete(p),app.GraphMarker);
            switch selectedTab.Title
                case 'component'
                    hold(app.Axes_graph,'on')
                    app.GraphMarker = cell(1,1);
                    app.GraphMarker{1} = plot3(app.Axes_graph,0,0,0,app.plot_op{:});
                    hold(app.Axes_graph,'off')
                    app.SelectComponentListBoxValueChanged([])
                case 'controller'
                    app.SelectControllerListBoxValueChanged([])
                case 'ground_fault'
            end
        end

        % Selection changed function: GraphmarkingButtonGroup
        function GraphmarkingButtonGroupSelectionChanged(app, event)
            selectedButton = app.GraphmarkingButtonGroup.SelectedObject;
            
            idx = app.SelectControllerListBox.Value.(selectedButton.Text);
            cellfun(@(p) delete(p),app.GraphMarker);
            hold(app.Axes_graph,'on')

            app.GraphMarker = cell(numel(idx),1);
            for i = 1:numel(idx)
                x = app.GraphData.a_component{idx(i)}.XData;
                y = app.GraphData.a_component{idx(i)}.YData;
                z = app.GraphData.a_component{idx(i)}.ZData;
                m = app.GraphData.a_component{idx(i)}.marker;
                s = app.GraphData.a_component{idx(i)}.size;
                app.GraphMarker{i} = plot3(app.Axes_graph,x,y,z,'Marker',m,'MarkerSize',s,app.plot_op{:});
            end
            hold(app.Axes_graph,'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.Axes_response = uiaxes(app.LeftPanel);
            title(app.Axes_response, 'Time Response')
            xlabel(app.Axes_response, 'X')
            ylabel(app.Axes_response, 'Y')
            zlabel(app.Axes_response, 'Z')
            app.Axes_graph = uiaxes(app.LeftPanel);
            xlabel(app.Axes_graph, 'X')
            ylabel(app.Axes_graph, 'Y')
            zlabel(app.Axes_graph, 'Z')
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'GUILDAicon.png';
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.Text = 'START';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SimulationSettingTab = uitab(app.TabGroup);
            app.SimulationSettingTab.Title = 'Simulation Setting';
            app.Tab_SImulationSetting = uitabgroup(app.SimulationSettingTab);
            app.Tab_SImulationSetting.SelectionChangedFcn = createCallbackFcn(app, @Tab_SImulationSettingSelectionChanged, true);
            app.Tab_component = uitab(app.Tab_SImulationSetting);
            app.Tab_component.Title = 'component';
            app.Panel_component = uipanel(app.Tab_component);
            app.Panel_component.Title = 'choose component';
            app.parallelSwitchLabel = uilabel(app.Panel_component);
            app.parallelSwitchLabel.Text = 'parallel';
            app.Switch_comp_parallel = uiswitch(app.Panel_component, 'toggle');
            app.Switch_comp_parallel.Items = {'off', 'on'};
            app.Switch_comp_parallel.ValueChangedFcn = createCallbackFcn(app, @Switch_comp_parallelValueChanged, true);
            app.Switch_comp_parallel.Value = 'off';
            app.Lamp_comp_parallel = uilamp(app.Panel_component);
            app.Tab_comp_parameter = uitable(app.Panel_component);
            app.Tab_comp_parameter.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tab_comp_parameter.RowName = {};
            app.Tab_comp_parameter.CellEditCallback = createCallbackFcn(app, @Tab_comp_parameterCellEdit, true);
            app.Tab_comp_ust = uitable(app.Panel_component);
            app.Tab_comp_ust.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tab_comp_ust.RowName = {};
            app.Tab_comp_ust.CellEditCallback = createCallbackFcn(app, @Tab_comp_ustCellEdit, true);
            app.parameterLabel = uilabel(app.Panel_component);
            app.parameterLabel.Text = 'parameter';
            app.u_equilibriumLabel = uilabel(app.Panel_component);
            app.u_equilibriumLabel.Text = 'u_equilibrium';
            app.SelectComponentLabel = uilabel(app.Tab_component);
            app.SelectComponentLabel.Text = 'Component : ';
            app.SelectComponentListBox = uilistbox(app.Tab_component);
            app.SelectComponentListBox.ValueChangedFcn = createCallbackFcn(app, @SelectComponentListBoxValueChanged, true);
            app.Tab_controller = uitab(app.Tab_SImulationSetting);
            app.Tab_controller.Title = 'controller';
            app.Panel_controller = uipanel(app.Tab_controller);
            app.Panel_controller.Title = 'choose controller';
            app.parallelSwitch_2Label = uilabel(app.Panel_controller);
            app.parallelSwitch_2Label.Text = 'parallel';
            app.Switch_con_parallel = uiswitch(app.Panel_controller, 'toggle');
            app.Switch_con_parallel.ValueChangedFcn = createCallbackFcn(app, @Switch_con_parallelValueChanged, true);
            app.Lamp_con_parallel = uilamp(app.Panel_controller);
            app.Tab_con_parameter = uitable(app.Panel_controller);
            app.Tab_con_parameter.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tab_con_parameter.RowName = {};
            app.Tab_con_parameter.CellEditCallback = createCallbackFcn(app, @Tab_con_parameterCellEdit, true);
            app.parameterLabel_2 = uilabel(app.Panel_controller);
            app.parameterLabel_2.Text = 'parameter';
            app.GraphmarkingButtonGroup = uibuttongroup(app.Panel_controller);
            app.GraphmarkingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphmarkingButtonGroupSelectionChanged, true);
            app.GraphmarkingButtonGroup.Title = 'Graph marking';
            app.index_observeButton = uitogglebutton(app.GraphmarkingButtonGroup);
            app.index_observeButton.Text = 'index_observe';
            app.index_observeButton.Value = true;
            app.index_inputButton = uitogglebutton(app.GraphmarkingButtonGroup);
            app.index_inputButton.Text = 'index_input';
            app.SelectControllerListBox = uilistbox(app.Tab_controller);
            app.SelectControllerListBox.ValueChangedFcn = createCallbackFcn(app, @SelectControllerListBoxValueChanged, true);
            app.SelectControllerLabel = uilabel(app.Tab_controller);
            app.SelectControllerLabel.Text = 'Select Controller : ';
            app.Tab_groundfault = uitab(app.Tab_SImulationSetting);
            app.Tab_groundfault.Title = 'ground fault';
            app.Button_fault = uibutton(app.Tab_groundfault, 'push');
            app.Button_fault.ButtonPushedFcn = createCallbackFcn(app, @Button_faultPushed, true);
            app.Button_fault.Text = 'fault';
            app.LengthoftimeofoccurrenceLabel = uilabel(app.Tab_groundfault);
            app.LengthoftimeofoccurrenceLabel.Text = 'Length of time of occurrence';
            app.TextBox_fault_time = uieditfield(app.Tab_groundfault, 'text');
            app.TextBox_fault_time.ValueChangedFcn = createCallbackFcn(app, @TextBox_fault_timeValueChanged, true);
            app.sLabel = uilabel(app.Tab_groundfault);
            app.sLabel.Text = '(s)';
            app.BusindexwheregroundfaultoccursEditFieldLabel = uilabel(app.Tab_groundfault);
            app.BusindexwheregroundfaultoccursEditFieldLabel.Text = 'Bus index where ground fault occurs';
            app.TextBox_fault_busidx = uieditfield(app.Tab_groundfault, 'text');
            app.TextBox_fault_busidx.ValueChangedFcn = createCallbackFcn(app, @TextBox_fault_busidxValueChanged, true);
            app.GraphPropertiesTab = uitab(app.TabGroup);
            app.GraphPropertiesTab.Title = 'Graph Properties';
            app.Button_2 = uibutton(app.GraphPropertiesTab, 'push');
            app.Button_3 = uibutton(app.GraphPropertiesTab, 'push');
            app.Button_4 = uibutton(app.GraphPropertiesTab, 'push');
            app.Button_5 = uibutton(app.GraphPropertiesTab, 'push');
            app.TabGroup2 = uitabgroup(app.GraphPropertiesTab);
            app.ComponentNodeTab = uitab(app.TabGroup2);
            app.ComponentNodeTab.Title = 'ComponentNode';
            app.Tab_GraphComponent = uitable(app.ComponentNodeTab);
            app.Tab_GraphComponent.ColumnName = {'No.'; 'color'; 'marker'; 'size'; 'XData'; 'YData'; 'ZData'};
            app.Tab_GraphComponent.RowName = {};
            app.BusNodeTab = uitab(app.TabGroup2);
            app.BusNodeTab.Title = 'BusNode';
            app.Tab_GraphBus = uitable(app.BusNodeTab);
            app.Tab_GraphBus.ColumnName = {'color'; 'marker'; 'size'; 'XData'; 'YData'; 'ZData'};
            app.Tab_GraphBus.RowName = {};
            app.ResponsePlotpropertiesTab = uitab(app.TabGroup);
            app.ResponsePlotpropertiesTab.Title = 'Response Plot properties';
            app.bus_window = uibuttongroup(app.ResponsePlotpropertiesTab);
            app.bus_window.Title = 'プロット対象母線';
            app.bus_idx = uitree(app.bus_window, 'checkbox');
            app.all_bus = uitreenode(app.bus_idx);
            app.all_bus.Tag = 'all_bus';
            app.all_bus.Text = '全ての母線';
            app.bus_type = uitreenode(app.all_bus);
            app.bus_type.Tag = 'all_bus';
            app.bus_type.Text = '母線の種類で選択';
            app.bus_PV = uitreenode(app.bus_type);
            app.bus_PV.Tag = 'bus_PV';
            app.bus_PV.Text = 'PV母線';
            app.bus_PQ = uitreenode(app.bus_type);
            app.bus_PQ.Tag = 'bus_PQ';
            app.bus_PQ.Text = 'PQ母線';
            app.bus_slack = uitreenode(app.bus_type);
            app.bus_slack.Tag = 'bus_slack';
            app.bus_slack.Text = 'slack母線';
            app.all_component = uitreenode(app.all_bus);
            app.all_component.Tag = 'all_bus';
            app.all_component.Text = '機器の種類で選択';
            % Assign Checked Nodes
            app.bus_idx.CheckedNodesChangedFcn = createCallbackFcn(app, @bus_idxCheckedNodesChanged, true);
            app.specify_tf = uicheckbox(app.bus_window);
            app.specify_tf.ValueChangedFcn = createCallbackFcn(app, @specify_tfValueChanged, true);
            app.specify_tf.Text = '特定の母線のみ';
            app.specify = uieditfield(app.bus_window, 'text');
            app.specify.ValueChangedFcn = createCallbackFcn(app, @specifyValueChanged, true);
            app.specify.Value = 'e.g. [1,2,3] or 1:3';
            app.para_window = uibuttongroup(app.ResponsePlotpropertiesTab);
            app.para_window.Title = 'プロット対象パラメータ';
            app.para = uitree(app.para_window, 'checkbox');
            app.powerflow = uitreenode(app.para);
            app.powerflow.Tag = 'powerflow';
            app.powerflow.Text = '潮流値';
            app.V = uitreenode(app.powerflow);
            app.V.Tag = 'V';
            app.V.Text = '母線電圧';
            app.Vabs = uitreenode(app.V);
            app.Vabs.Tag = 'Vabs';
            app.Vabs.Text = '絶対値';
            app.Vangle = uitreenode(app.V);
            app.Vangle.Tag = 'Vangle';
            app.Vangle.Text = '偏角';
            app.Vreal = uitreenode(app.V);
            app.Vreal.Tag = 'Vreal';
            app.Vreal.Text = '実部';
            app.Vimag = uitreenode(app.V);
            app.Vimag.Tag = 'Vimag';
            app.Vimag.Text = '虚部';
            app.I = uitreenode(app.powerflow);
            app.I.Tag = 'I';
            app.I.Text = '母線電流';
            app.Iabs = uitreenode(app.I);
            app.Iabs.Tag = 'Iabs';
            app.Iabs.Text = '絶対値';
            app.Iangle = uitreenode(app.I);
            app.Iangle.Tag = 'Iangle';
            app.Iangle.Text = '偏角';
            app.Ireal = uitreenode(app.I);
            app.Ireal.Tag = 'Ireal';
            app.Ireal.Text = '実部';
            app.Iimag = uitreenode(app.I);
            app.Iimag.Tag = 'Iimag';
            app.Iimag.Text = '虚部';
            app.power = uitreenode(app.powerflow);
            app.power.Tag = 'power';
            app.power.Text = '電力';
            app.P = uitreenode(app.power);
            app.P.Tag = 'P';
            app.P.Text = '有効電力';
            app.Q = uitreenode(app.power);
            app.Q.Tag = 'Q';
            app.Q.Text = '無効電力';
            app.S = uitreenode(app.power);
            app.S.Tag = 'S';
            app.S.Text = '皮相電力';
            app.Factor = uitreenode(app.power);
            app.Factor.Tag = 'Factor';
            app.Factor.Text = '力率';
            app.X = uitreenode(app.para);
            app.X.Tag = 'X';
            app.X.Text = '機器の状態';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.Text = 'QUIT';
            app.LIVESimulationLabel = uilabel(app.LeftPanel);
            app.LIVESimulationLabel.Text = 'LIVE Simulation';
            app.NetworkGraphLabel = uilabel(app.LeftPanel);
            app.NetworkGraphLabel.Text = 'Network Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveSimulation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end